classdef DASTchannels < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ChannelsMaskerEditField       matlab.ui.control.EditField
        ChannelsMaskerEditFieldLabel  matlab.ui.control.Label
        ChannelsTargetEditField       matlab.ui.control.EditField
        ChannelsTargetEditFieldLabel  matlab.ui.control.Label
        UploadedPresetEditField       matlab.ui.control.EditField
        CloseButton                   matlab.ui.control.Button
        ApplyConfigurationButton      matlab.ui.control.Button
        SavePresetButton              matlab.ui.control.Button
        LoadPresetButton              matlab.ui.control.Button
        ChannelTitleLabel             matlab.ui.control.Label
        MaskerspeakerPanel            matlab.ui.container.Panel
        SpeakerMasker24               matlab.ui.control.NumericEditField
        SpeakerMasker23               matlab.ui.control.NumericEditField
        SpeakerMasker22               matlab.ui.control.NumericEditField
        SpeakerMasker21               matlab.ui.control.NumericEditField
        SpeakerMasker20               matlab.ui.control.NumericEditField
        SpeakerMasker19               matlab.ui.control.NumericEditField
        SpeakerMasker18               matlab.ui.control.NumericEditField
        SpeakerMasker17               matlab.ui.control.NumericEditField
        SpeakerMasker16               matlab.ui.control.NumericEditField
        SpeakerMasker15               matlab.ui.control.NumericEditField
        SpeakerMasker14               matlab.ui.control.NumericEditField
        SpeakerMasker13               matlab.ui.control.NumericEditField
        SpeakerMasker12               matlab.ui.control.NumericEditField
        SpeakerMasker11               matlab.ui.control.NumericEditField
        SpeakerMasker10               matlab.ui.control.NumericEditField
        SpeakerMasker9                matlab.ui.control.NumericEditField
        SpeakerMasker8                matlab.ui.control.NumericEditField
        SpeakerMasker7                matlab.ui.control.NumericEditField
        SpeakerMasker6                matlab.ui.control.NumericEditField
        SpeakerMasker5                matlab.ui.control.NumericEditField
        SpeakerMasker4                matlab.ui.control.NumericEditField
        SpeakerMasker3                matlab.ui.control.NumericEditField
        SpeakerMasker2                matlab.ui.control.NumericEditField
        SpeakerMasker1                matlab.ui.control.NumericEditField
        CenterMasker                  matlab.ui.control.Label
        ButtonMasker22                matlab.ui.control.StateButton
        ButtonMasker19                matlab.ui.control.StateButton
        ButtonMasker16                matlab.ui.control.StateButton
        ButtonMasker13                matlab.ui.control.StateButton
        ButtonMasker10                matlab.ui.control.StateButton
        ButtonMasker7                 matlab.ui.control.StateButton
        ButtonMasker4                 matlab.ui.control.StateButton
        ButtonMasker1                 matlab.ui.control.StateButton
        ButtonMasker18                matlab.ui.control.StateButton
        ButtonMasker17                matlab.ui.control.StateButton
        ButtonMasker15                matlab.ui.control.StateButton
        ButtonMasker14                matlab.ui.control.StateButton
        ButtonMasker12                matlab.ui.control.StateButton
        ButtonMasker11                matlab.ui.control.StateButton
        ButtonMasker9                 matlab.ui.control.StateButton
        ButtonMasker8                 matlab.ui.control.StateButton
        ButtonMasker20                matlab.ui.control.StateButton
        ButtonMasker21                matlab.ui.control.StateButton
        ButtonMasker23                matlab.ui.control.StateButton
        ButtonMasker24                matlab.ui.control.StateButton
        ButtonMasker6                 matlab.ui.control.StateButton
        ButtonMasker5                 matlab.ui.control.StateButton
        ButtonMasker3                 matlab.ui.control.StateButton
        ButtonMasker2                 matlab.ui.control.StateButton
        TargetspeakerPanel            matlab.ui.container.Panel
        SpeakerTarget24               matlab.ui.control.NumericEditField
        SpeakerTarget23               matlab.ui.control.NumericEditField
        SpeakerTarget22               matlab.ui.control.NumericEditField
        SpeakerTarget21               matlab.ui.control.NumericEditField
        SpeakerTarget20               matlab.ui.control.NumericEditField
        SpeakerTarget19               matlab.ui.control.NumericEditField
        SpeakerTarget18               matlab.ui.control.NumericEditField
        SpeakerTarget17               matlab.ui.control.NumericEditField
        SpeakerTarget16               matlab.ui.control.NumericEditField
        SpeakerTarget15               matlab.ui.control.NumericEditField
        SpeakerTarget14               matlab.ui.control.NumericEditField
        SpeakerTarget13               matlab.ui.control.NumericEditField
        SpeakerTarget12               matlab.ui.control.NumericEditField
        SpeakerTarget11               matlab.ui.control.NumericEditField
        SpeakerTarget10               matlab.ui.control.NumericEditField
        SpeakerTarget9                matlab.ui.control.NumericEditField
        SpeakerTarget8                matlab.ui.control.NumericEditField
        SpeakerTarget7                matlab.ui.control.NumericEditField
        SpeakerTarget6                matlab.ui.control.NumericEditField
        SpeakerTarget5                matlab.ui.control.NumericEditField
        SpeakerTarget4                matlab.ui.control.NumericEditField
        SpeakerTarget3                matlab.ui.control.NumericEditField
        SpeakerTarget2                matlab.ui.control.NumericEditField
        SpeakerTarget1                matlab.ui.control.NumericEditField
        CenterTarget                  matlab.ui.control.Label
        ButtonTarget22                matlab.ui.control.StateButton
        ButtonTarget19                matlab.ui.control.StateButton
        ButtonTarget16                matlab.ui.control.StateButton
        ButtonTarget13                matlab.ui.control.StateButton
        ButtonTarget10                matlab.ui.control.StateButton
        ButtonTarget7                 matlab.ui.control.StateButton
        ButtonTarget4                 matlab.ui.control.StateButton
        ButtonTarget1                 matlab.ui.control.StateButton
        ButtonTarget18                matlab.ui.control.StateButton
        ButtonTarget17                matlab.ui.control.StateButton
        ButtonTarget15                matlab.ui.control.StateButton
        ButtonTarget14                matlab.ui.control.StateButton
        ButtonTarget12                matlab.ui.control.StateButton
        ButtonTarget11                matlab.ui.control.StateButton
        ButtonTarget9                 matlab.ui.control.StateButton
        ButtonTarget8                 matlab.ui.control.StateButton
        ButtonTarget20                matlab.ui.control.StateButton
        ButtonTarget21                matlab.ui.control.StateButton
        ButtonTarget23                matlab.ui.control.StateButton
        ButtonTarget24                matlab.ui.control.StateButton
        ButtonTarget6                 matlab.ui.control.StateButton
        ButtonTarget5                 matlab.ui.control.StateButton
        ButtonTarget3                 matlab.ui.control.StateButton
        ButtonTarget2                 matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        CallingApp % DASTsettings

    end
    
    properties (Access = public)
        % Definition of global variables

        % Everything that gets saved in result file (i.e., DATA)
        data

        % Everything that doesnt get saved but persists throughout the session (i.e., PERSISTENT)
        pers

    end
    
    methods (Access = private)
        
        function updateChannels(app)

            % Full channel configuration
            app.data.Settings.PlaybackConfigurationTarget = [];
            app.data.Settings.PlaybackConfigurationMasker = [];
             
            % If any of the selection is changed, update channels fields
            totalSpeakers = 24;
            PlaybackChannelsTarget = [];
            PlaybackChannelsMasker = [];
            PlaybackChannelsTargetAngles = [];
            PlaybackChannelsMaskerAngles = [];

            for button = 1:totalSpeakers
                % TARGET
                app.data.Settings.PlaybackConfigurationTarget =...
                    [app.data.Settings.PlaybackConfigurationTarget, app.(sprintf('SpeakerTarget%i', button)).Value];
               
                % Update vector for channels (if active)
                if app.(sprintf('ButtonTarget%i', button)).Value == 1
                    % Get channel number
                    whichChannel = app.(sprintf('SpeakerTarget%i', button)).Value;
                    PlaybackChannelsTarget = [PlaybackChannelsTarget, whichChannel];

                   % Get corresponding angle
                   whichAngle = app.data.Settings.PlaybackConfigurationAngles(button);
                   PlaybackChannelsTargetAngles = [PlaybackChannelsTargetAngles, whichAngle];

                end


                % MASKER
                app.data.Settings.PlaybackConfigurationMasker =...
                    [app.data.Settings.PlaybackConfigurationMasker, app.(sprintf('SpeakerMasker%i', button)).Value];

                % Update vector for channels (if active)
                if app.(sprintf('ButtonMasker%i', button)).Value == 1
                    % Get channel number
                    whichChannel = app.(sprintf('SpeakerMasker%i', button)).Value;
                    PlaybackChannelsMasker = [PlaybackChannelsMasker, whichChannel];

                   % Get corresponding angle
                   whichAngle = app.data.Settings.PlaybackConfigurationAngles(button);
                   PlaybackChannelsMaskerAngles = [PlaybackChannelsMaskerAngles, whichAngle];

                end
            end

            % Save and update global variable
            app.data.Settings.PlaybackChannelsTarget = unique(PlaybackChannelsTarget);
            app.data.Settings.PlaybackChannelsMasker = unique(PlaybackChannelsMasker);
            app.data.Settings.PlaybackChannelsTargetAngles = PlaybackChannelsTargetAngles;
            app.data.Settings.PlaybackChannelsMaskerAngles = PlaybackChannelsMaskerAngles;

            % Update fields in UI
            app.ChannelsTargetEditField.Value = num2str(unique(PlaybackChannelsTarget));
            app.ChannelsMaskerEditField.Value = num2str(unique(PlaybackChannelsMasker));

            % Update current preset name
            app.pers.UploadedPreset = app.UploadedPresetEditField.Value;

        end
        

        function writeConfigurationToInterface(app)

            totalSpeakers = 24;

            for button = 1:totalSpeakers

                % TARGET
                % Set value for each channel
                app.(sprintf('SpeakerTarget%i', button)).Value = app.data.Settings.PlaybackConfigurationTarget(button);
                
                % Check if button is active
                whichAngle = app.data.Settings.PlaybackConfigurationAngles(button);
                if any(app.data.Settings.PlaybackChannelsTargetAngles == whichAngle)
                    app.(sprintf('ButtonTarget%i', button)).Value = 1;
                    app.(sprintf('ButtonTarget%i', button)).BackgroundColor = 'g';
                    app.(sprintf('SpeakerTarget%i', button)).Enable = "on";
                else
                    app.(sprintf('ButtonTarget%i', button)).Value = 0;
                    app.(sprintf('ButtonTarget%i', button)).BackgroundColor = 'r';
                    app.(sprintf('SpeakerTarget%i', button)).Enable = "off";
                end

                % MASKER
                % Set value for each channel
                app.(sprintf('SpeakerMasker%i', button)).Value = app.data.Settings.PlaybackConfigurationTarget(button);
                
                % Check if button is active
                whichAngle = app.data.Settings.PlaybackConfigurationAngles(button);
                if any(app.data.Settings.PlaybackChannelsMaskerAngles == whichAngle)
                    app.(sprintf('ButtonMasker%i', button)).Value = 1;
                    app.(sprintf('ButtonMasker%i', button)).BackgroundColor = 'g';
                    app.(sprintf('SpeakerMasker%i', button)).Enable = "on";
                else
                    app.(sprintf('ButtonMasker%i', button)).Value = 0;
                    app.(sprintf('ButtonMasker%i', button)).BackgroundColor = 'r';
                    app.(sprintf('SpeakerMasker%i', button)).Enable = "off";
                end

                % Update fields in UI
                app.ChannelsTargetEditField.Value = num2str(unique(app.data.Settings.PlaybackChannelsTarget));
                app.ChannelsMaskerEditField.Value = num2str(unique(app.data.Settings.PlaybackChannelsMasker));

                % Write name of current preset
                app.UploadedPresetEditField.Value = app.pers.UploadedPreset;

            end
        end

        function placeSpeakersInUI(app)
            % Correct placement of loudspeakers and channels in UI
            
            % PLACE LOUDSPEAKERS AND CHANNEL FIELDS
            % Center of target and maskers
            xTarget = app.CenterTarget.Position(1) + app.CenterTarget.Position(3)/2;
            yTarget = app.CenterTarget.Position(2) + app.CenterTarget.Position(4)/2;
            
            xMasker = app.CenterMasker.Position(1) + app.CenterMasker.Position(3)/2;
            yMasker = app.CenterMasker.Position(2) + app.CenterMasker.Position(4)/2;
            
            speakerR = 130;
            buttonR = 105;

            % Iterate for each button (loudspeaker in circumference)
            totalSpeakers = 24;
            thFraction = 360/24;
            theta = 90;

            % Define angles of loudspeakers
            app.data.Settings.PlaybackConfigurationAngles = [(0:thFraction:180) (-165:thFraction:-15)];

            % Placing the objects in the UI
            for button = 1:totalSpeakers

                % TARGET SPEAKERS
                % Buttons to select speaker
                x = buttonR*cosd(theta) + xTarget - app.(sprintf('ButtonTarget%i', button)).Position(3)/2;
                y = buttonR*sind(theta) + yTarget - app.(sprintf('ButtonTarget%i', button)).Position(4)/2;
                app.(sprintf('ButtonTarget%i', button)).Position(1) = x;
                app.(sprintf('ButtonTarget%i', button)).Position(2) = y;

                % Number of each channel
                x = speakerR*cosd(theta) + xTarget - app.(sprintf('SpeakerTarget%i', button)).Position(3)/2;
                y = speakerR*sind(theta) + yTarget - app.(sprintf('SpeakerTarget%i', button)).Position(4)/2;
                app.(sprintf('SpeakerTarget%i', button)).Position(1) = x;
                app.(sprintf('SpeakerTarget%i', button)).Position(2) = y;

                
                % MASKER SPEAKERS
                % Buttons to select speaker
                x = buttonR*cosd(theta) + xMasker - app.(sprintf('ButtonMasker%i', button)).Position(3)/2;
                y = buttonR*sind(theta) + yMasker - app.(sprintf('ButtonMasker%i', button)).Position(4)/2;
                app.(sprintf('ButtonMasker%i', button)).Position(1) = x;
                app.(sprintf('ButtonMasker%i', button)).Position(2) = y;

                % Number of each channel
                x = speakerR*cosd(theta) + xMasker - app.(sprintf('SpeakerMasker%i', button)).Position(3)/2;
                y = speakerR*sind(theta) + yMasker - app.(sprintf('SpeakerMasker%i', button)).Position(4)/2;
                app.(sprintf('SpeakerMasker%i', button)).Position(1) = x;
                app.(sprintf('SpeakerMasker%i', button)).Position(2) = y;


                % Upadting angle for next iteration
                theta = theta - thFraction;
            end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, settingsApp, data, pers)

            % Store settings app in property for CloseRequestFcn to use
            app.CallingApp = settingsApp;

            % Intialize variables
            app.data = data; 
            app.pers = pers;

            app.data.Settings = app.data.Settings; % everything related to channel specification

            % Correct placement of elements in UI
            placeSpeakersInUI(app)

            if isfield(app.data.Settings,'PlaybackConfigurationTarget')
                % Updating channel configuration with current variable
                writeConfigurationToInterface(app);
            end

        end

        % Value changed function: ButtonMasker1, ButtonMasker10, 
        % ...and 46 other components
        function ButtonSpeakerValueChanged(app, event)
            
            app.SavePresetButton.Enable = "on";
            app.ApplyConfigurationButton.Enable = "on";
            app.UploadedPresetEditField.Value = '';

            % (de)activate button and corresponding edit field
            value = event.Value;
            whichButton= event.Source.Tag;

            % Activate speaker button and respective channel number
            if value == 1
                event.Source.BackgroundColor = 'g';
                app.(sprintf('Speaker%s', whichButton)).Enable = "on";

            % Deactivate speaker button and respective channel number
            else
                event.Source.BackgroundColor = 'r';
                app.(sprintf('Speaker%s', whichButton)).Enable = "off";

            end

            % Update vector for channels
            updateChannels(app);

        end

        % Value changed function: SpeakerMasker1, SpeakerMasker10, 
        % ...and 46 other components
        function SpeakerChannelValueChanged(app, event)
            
            app.SavePresetButton.Enable = "on";
            app.ApplyConfigurationButton.Enable = "on";
            app.UploadedPresetEditField.Value = '';

            % Change the channel number for corresponding target/masker
            newValue = event.Value;
            whichChannel= str2num(event.Source.Tag);

            % Update channel for both target and masker
            app.(sprintf('SpeakerTarget%i', whichChannel)).Value = newValue;
            app.(sprintf('SpeakerMasker%i', whichChannel)).Value = newValue;

            % Update vector for channels
            updateChannels(app);

        end

        % Button pushed function: SavePresetButton
        function SavePresetButtonPushed(app, event)
            % Get user input for filename - channel configuration
            [file,path] = uiputfile(fullfile(app.pers.presetDir,'*.mat'),'Save configuration as a preset');

            if file==0 % user pressed cancel
                drawnow;
                figure(app.UIFigure);
                return;
            else

                Settings = app.data.Settings;
                fullfilename = fullfile(path, file);
                save(fullfilename, 'Settings', '-mat');
                [~,f,~]=fileparts(fullfilename);
                filename=fullfile(f);
                app.UploadedPresetEditField.Value = filename;
                app.pers.UploadedPreset = app.UploadedPresetEditField.Value;

                drawnow;
                figure(app.UIFigure);
                app.SavePresetButton.Enable = "off";
                app.ApplyConfigurationButton.Enable = "off";

            end
            % Get focus back to the main figure
            focus(app.UIFigure);

        end

        % Button pushed function: LoadPresetButton
        function LoadPresetButtonPushed(app, event)
            [filename,pathname] = uigetfile(fullfile(app.pers.presetDir,'*.mat'));

            if filename==0
                % user pressed cancel
                drawnow;
                figure(app.UIFigure);
                return
            else
                % loading variable
                fullfilename = fullfile(pathname,filename);
                load(fullfilename,'Settings');
                app.data.Settings = Settings;

                [~,f,~]=fileparts(fullfilename);
                filename=fullfile(f);
                app.UploadedPresetEditField.Value = filename;
                app.pers.UploadedPreset = app.UploadedPresetEditField.Value;

                % Updating channel configuration with current variable
                writeConfigurationToInterface(app);

                drawnow;
                figure(app.UIFigure);
            end

            % Get focus back to the main figure
            focus(app.UIFigure);
        end

        % Button pushed function: ApplyConfigurationButton
        function ApplyConfigurationButtonPushed(app, event)
            
            app.ApplyConfigurationButton.Enable = "off";

            % Export data to main function
            transferChannelConfiguration(app.CallingApp, app.data, app.pers);

            % Get focus back to the main figure
            focus(app.UIFigure);

        end

        % Callback function: CloseButton, UIFigure
        function UIFigureCloseRequest(app, event)
            if app.ApplyConfigurationButton.Enable == "on"
                selection = uiconfirm(app.UIFigure,'Do you want to apply or revert your changes?','Channel configuration','Options',{'Apply','Revert'});
                if strcmpi(selection,'Apply')
                    ApplyConfigurationButtonPushed(app)
                    figure(app.CallingApp.UIFigure);
                    delete(app);
                elseif strcmpi(selection, 'Revert')
                    writeConfigurationToInterface(app);
                    figure(app.CallingApp.UIFigure);
                    delete(app)
                else
                    % if user presses X to cancel
                    return
                end
            else
                % Apply settings and close app
                ApplyConfigurationButtonPushed(app)
                app.ApplyConfigurationButton.Enable = "off";
                figure(app.CallingApp.UIFigure);
                delete(app)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TargetspeakerPanel = uipanel(app.UIFigure);
            app.TargetspeakerPanel.Title = 'Target speaker';
            app.ButtonTarget2 = uibutton(app.TargetspeakerPanel, 'state');
            app.ButtonTarget2.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonTarget2.Tag = 'Target2';
            app.ButtonTarget2.Tooltip = {'+15°'};
            app.ButtonTarget2.Text = '';
            app.ButtonTarget3 = uibutton(app.TargetspeakerPanel, 'state');
            app.ButtonTarget3.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonTarget3.Tag = 'Target3';
            app.ButtonTarget3.Tooltip = {'+30°'};
            app.ButtonTarget3.Text = '';
            app.ButtonTarget5 = uibutton(app.TargetspeakerPanel, 'state');
            app.ButtonTarget5.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonTarget5.Tag = 'Target5';
            app.ButtonTarget5.Tooltip = {'+60°'};
            app.ButtonTarget5.Text = '';
            app.ButtonTarget6 = uibutton(app.TargetspeakerPanel, 'state');
            app.ButtonTarget6.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonTarget6.Tag = 'Target6';
            app.ButtonTarget6.Tooltip = {'+75°'};
            app.ButtonTarget6.Text = '';
            app.ButtonTarget24 = uibutton(app.TargetspeakerPanel, 'state');
            app.ButtonTarget24.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonTarget24.Tag = 'Target24';
            app.ButtonTarget24.Tooltip = {'-15°'};
            app.ButtonTarget24.Text = '';
            app.ButtonTarget23 = uibutton(app.TargetspeakerPanel, 'state');
            app.ButtonTarget23.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonTarget23.Tag = 'Target23';
            app.ButtonTarget23.Tooltip = {'-30°'};
            app.ButtonTarget23.Text = '';
            app.ButtonTarget21 = uibutton(app.TargetspeakerPanel, 'state');
            app.ButtonTarget21.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonTarget21.Tag = 'Target21';
            app.ButtonTarget21.Tooltip = {'-60°'};
            app.ButtonTarget21.Text = '';
            app.ButtonTarget20 = uibutton(app.TargetspeakerPanel, 'state');
            app.ButtonTarget20.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonTarget20.Tag = 'Target20';
            app.ButtonTarget20.Tooltip = {'-75°'};
            app.ButtonTarget20.Text = '';
            app.ButtonTarget8 = uibutton(app.TargetspeakerPanel, 'state');
            app.ButtonTarget8.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonTarget8.Tag = 'Target8';
            app.ButtonTarget8.Tooltip = {'+105°'};
            app.ButtonTarget8.Text = '';
            app.ButtonTarget9 = uibutton(app.TargetspeakerPanel, 'state');
            app.ButtonTarget9.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonTarget9.Tag = 'Target9';
            app.ButtonTarget9.Tooltip = {'+120°'};
            app.ButtonTarget9.Text = '';
            app.ButtonTarget11 = uibutton(app.TargetspeakerPanel, 'state');
            app.ButtonTarget11.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonTarget11.Tag = 'Target11';
            app.ButtonTarget11.Tooltip = {'+150°'};
            app.ButtonTarget11.Text = '';
            app.ButtonTarget12 = uibutton(app.TargetspeakerPanel, 'state');
            app.ButtonTarget12.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonTarget12.Tag = 'Target12';
            app.ButtonTarget12.Tooltip = {'+165°'};
            app.ButtonTarget12.Text = '';
            app.ButtonTarget14 = uibutton(app.TargetspeakerPanel, 'state');
            app.ButtonTarget14.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonTarget14.Tag = 'Target14';
            app.ButtonTarget14.Tooltip = {'-165°'};
            app.ButtonTarget14.Text = '';
            app.ButtonTarget15 = uibutton(app.TargetspeakerPanel, 'state');
            app.ButtonTarget15.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonTarget15.Tag = 'Target15';
            app.ButtonTarget15.Tooltip = {'-150°'};
            app.ButtonTarget15.Text = '';
            app.ButtonTarget17 = uibutton(app.TargetspeakerPanel, 'state');
            app.ButtonTarget17.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonTarget17.Tag = 'Target17';
            app.ButtonTarget17.Tooltip = {'-120°'};
            app.ButtonTarget17.Text = '';
            app.ButtonTarget18 = uibutton(app.TargetspeakerPanel, 'state');
            app.ButtonTarget18.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonTarget18.Tag = 'Target18';
            app.ButtonTarget18.Tooltip = {'-105°'};
            app.ButtonTarget18.Text = '';
            app.ButtonTarget1 = uibutton(app.TargetspeakerPanel, 'state');
            app.ButtonTarget1.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonTarget1.Tag = 'Target1';
            app.ButtonTarget1.Tooltip = {'0°'};
            app.ButtonTarget1.Text = '';
            app.ButtonTarget1.Value = true;
            app.ButtonTarget4 = uibutton(app.TargetspeakerPanel, 'state');
            app.ButtonTarget4.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonTarget4.Tag = 'Target4';
            app.ButtonTarget4.Tooltip = {'+45°'};
            app.ButtonTarget4.Text = '';
            app.ButtonTarget7 = uibutton(app.TargetspeakerPanel, 'state');
            app.ButtonTarget7.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonTarget7.Tag = 'Target7';
            app.ButtonTarget7.Tooltip = {'+90°'};
            app.ButtonTarget7.Text = '';
            app.ButtonTarget10 = uibutton(app.TargetspeakerPanel, 'state');
            app.ButtonTarget10.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonTarget10.Tag = 'Target10';
            app.ButtonTarget10.Tooltip = {'+135°'};
            app.ButtonTarget10.Text = '';
            app.ButtonTarget13 = uibutton(app.TargetspeakerPanel, 'state');
            app.ButtonTarget13.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonTarget13.Tag = 'Target13';
            app.ButtonTarget13.Tooltip = {'180°'};
            app.ButtonTarget13.Text = '';
            app.ButtonTarget16 = uibutton(app.TargetspeakerPanel, 'state');
            app.ButtonTarget16.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonTarget16.Tag = 'Target16';
            app.ButtonTarget16.Tooltip = {'-135°'};
            app.ButtonTarget16.Text = '';
            app.ButtonTarget19 = uibutton(app.TargetspeakerPanel, 'state');
            app.ButtonTarget19.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonTarget19.Tag = 'Target19';
            app.ButtonTarget19.Tooltip = {'-90°'};
            app.ButtonTarget19.Text = '';
            app.ButtonTarget22 = uibutton(app.TargetspeakerPanel, 'state');
            app.ButtonTarget22.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonTarget22.Tag = 'Target22';
            app.ButtonTarget22.Tooltip = {'-45°'};
            app.ButtonTarget22.Text = '';
            app.CenterTarget = uilabel(app.TargetspeakerPanel);
            app.CenterTarget.Text = '△';
            app.SpeakerTarget1 = uieditfield(app.TargetspeakerPanel, 'numeric');
            app.SpeakerTarget1.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerTarget1.Tag = '1';
            app.SpeakerTarget1.Value = 1;
            app.SpeakerTarget2 = uieditfield(app.TargetspeakerPanel, 'numeric');
            app.SpeakerTarget2.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerTarget2.Tag = '2';
            app.SpeakerTarget2.Enable = 'off';
            app.SpeakerTarget2.Value = 2;
            app.SpeakerTarget3 = uieditfield(app.TargetspeakerPanel, 'numeric');
            app.SpeakerTarget3.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerTarget3.Tag = '3';
            app.SpeakerTarget3.Enable = 'off';
            app.SpeakerTarget3.Value = 3;
            app.SpeakerTarget4 = uieditfield(app.TargetspeakerPanel, 'numeric');
            app.SpeakerTarget4.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerTarget4.Tag = '4';
            app.SpeakerTarget4.Enable = 'off';
            app.SpeakerTarget4.Value = 4;
            app.SpeakerTarget5 = uieditfield(app.TargetspeakerPanel, 'numeric');
            app.SpeakerTarget5.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerTarget5.Tag = '5';
            app.SpeakerTarget5.Enable = 'off';
            app.SpeakerTarget5.Value = 5;
            app.SpeakerTarget6 = uieditfield(app.TargetspeakerPanel, 'numeric');
            app.SpeakerTarget6.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerTarget6.Tag = '6';
            app.SpeakerTarget6.Enable = 'off';
            app.SpeakerTarget6.Value = 6;
            app.SpeakerTarget7 = uieditfield(app.TargetspeakerPanel, 'numeric');
            app.SpeakerTarget7.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerTarget7.Tag = '7';
            app.SpeakerTarget7.Enable = 'off';
            app.SpeakerTarget7.Value = 7;
            app.SpeakerTarget8 = uieditfield(app.TargetspeakerPanel, 'numeric');
            app.SpeakerTarget8.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerTarget8.Tag = '8';
            app.SpeakerTarget8.Enable = 'off';
            app.SpeakerTarget8.Value = 8;
            app.SpeakerTarget9 = uieditfield(app.TargetspeakerPanel, 'numeric');
            app.SpeakerTarget9.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerTarget9.Tag = '9';
            app.SpeakerTarget9.Enable = 'off';
            app.SpeakerTarget9.Value = 9;
            app.SpeakerTarget10 = uieditfield(app.TargetspeakerPanel, 'numeric');
            app.SpeakerTarget10.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerTarget10.Tag = '10';
            app.SpeakerTarget10.Enable = 'off';
            app.SpeakerTarget10.Value = 10;
            app.SpeakerTarget11 = uieditfield(app.TargetspeakerPanel, 'numeric');
            app.SpeakerTarget11.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerTarget11.Tag = '11';
            app.SpeakerTarget11.Enable = 'off';
            app.SpeakerTarget11.Value = 11;
            app.SpeakerTarget12 = uieditfield(app.TargetspeakerPanel, 'numeric');
            app.SpeakerTarget12.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerTarget12.Tag = '12';
            app.SpeakerTarget12.Enable = 'off';
            app.SpeakerTarget12.Value = 12;
            app.SpeakerTarget13 = uieditfield(app.TargetspeakerPanel, 'numeric');
            app.SpeakerTarget13.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerTarget13.Tag = '13';
            app.SpeakerTarget13.Enable = 'off';
            app.SpeakerTarget13.Value = 13;
            app.SpeakerTarget14 = uieditfield(app.TargetspeakerPanel, 'numeric');
            app.SpeakerTarget14.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerTarget14.Tag = '14';
            app.SpeakerTarget14.Enable = 'off';
            app.SpeakerTarget14.Value = 14;
            app.SpeakerTarget15 = uieditfield(app.TargetspeakerPanel, 'numeric');
            app.SpeakerTarget15.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerTarget15.Tag = '15';
            app.SpeakerTarget15.Enable = 'off';
            app.SpeakerTarget15.Value = 15;
            app.SpeakerTarget16 = uieditfield(app.TargetspeakerPanel, 'numeric');
            app.SpeakerTarget16.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerTarget16.Tag = '16';
            app.SpeakerTarget16.Enable = 'off';
            app.SpeakerTarget16.Value = 16;
            app.SpeakerTarget17 = uieditfield(app.TargetspeakerPanel, 'numeric');
            app.SpeakerTarget17.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerTarget17.Tag = '17';
            app.SpeakerTarget17.Enable = 'off';
            app.SpeakerTarget17.Value = 17;
            app.SpeakerTarget18 = uieditfield(app.TargetspeakerPanel, 'numeric');
            app.SpeakerTarget18.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerTarget18.Tag = '18';
            app.SpeakerTarget18.Enable = 'off';
            app.SpeakerTarget18.Value = 18;
            app.SpeakerTarget19 = uieditfield(app.TargetspeakerPanel, 'numeric');
            app.SpeakerTarget19.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerTarget19.Tag = '19';
            app.SpeakerTarget19.Enable = 'off';
            app.SpeakerTarget19.Value = 19;
            app.SpeakerTarget20 = uieditfield(app.TargetspeakerPanel, 'numeric');
            app.SpeakerTarget20.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerTarget20.Tag = '20';
            app.SpeakerTarget20.Enable = 'off';
            app.SpeakerTarget20.Value = 20;
            app.SpeakerTarget21 = uieditfield(app.TargetspeakerPanel, 'numeric');
            app.SpeakerTarget21.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerTarget21.Tag = '21';
            app.SpeakerTarget21.Enable = 'off';
            app.SpeakerTarget21.Value = 21;
            app.SpeakerTarget22 = uieditfield(app.TargetspeakerPanel, 'numeric');
            app.SpeakerTarget22.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerTarget22.Tag = '22';
            app.SpeakerTarget22.Enable = 'off';
            app.SpeakerTarget22.Value = 22;
            app.SpeakerTarget23 = uieditfield(app.TargetspeakerPanel, 'numeric');
            app.SpeakerTarget23.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerTarget23.Tag = '23';
            app.SpeakerTarget23.Enable = 'off';
            app.SpeakerTarget23.Value = 23;
            app.SpeakerTarget24 = uieditfield(app.TargetspeakerPanel, 'numeric');
            app.SpeakerTarget24.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerTarget24.Tag = '24';
            app.SpeakerTarget24.Enable = 'off';
            app.SpeakerTarget24.Value = 24;
            app.MaskerspeakerPanel = uipanel(app.UIFigure);
            app.MaskerspeakerPanel.Title = 'Masker speaker';
            app.ButtonMasker2 = uibutton(app.MaskerspeakerPanel, 'state');
            app.ButtonMasker2.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonMasker2.Tag = 'Masker2';
            app.ButtonMasker2.Tooltip = {'+15°'};
            app.ButtonMasker2.Text = '';
            app.ButtonMasker3 = uibutton(app.MaskerspeakerPanel, 'state');
            app.ButtonMasker3.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonMasker3.Tag = 'Masker3';
            app.ButtonMasker3.Tooltip = {'+30°'};
            app.ButtonMasker3.Text = '';
            app.ButtonMasker5 = uibutton(app.MaskerspeakerPanel, 'state');
            app.ButtonMasker5.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonMasker5.Tag = 'Masker5';
            app.ButtonMasker5.Tooltip = {'+60°'};
            app.ButtonMasker5.Text = '';
            app.ButtonMasker6 = uibutton(app.MaskerspeakerPanel, 'state');
            app.ButtonMasker6.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonMasker6.Tag = 'Masker6';
            app.ButtonMasker6.Tooltip = {'+75°'};
            app.ButtonMasker6.Text = '';
            app.ButtonMasker24 = uibutton(app.MaskerspeakerPanel, 'state');
            app.ButtonMasker24.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonMasker24.Tag = 'Masker24';
            app.ButtonMasker24.Tooltip = {'-15°'};
            app.ButtonMasker24.Text = '';
            app.ButtonMasker23 = uibutton(app.MaskerspeakerPanel, 'state');
            app.ButtonMasker23.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonMasker23.Tag = 'Masker23';
            app.ButtonMasker23.Tooltip = {'-30°'};
            app.ButtonMasker23.Text = '';
            app.ButtonMasker21 = uibutton(app.MaskerspeakerPanel, 'state');
            app.ButtonMasker21.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonMasker21.Tag = 'Masker21';
            app.ButtonMasker21.Tooltip = {'-60°'};
            app.ButtonMasker21.Text = '';
            app.ButtonMasker20 = uibutton(app.MaskerspeakerPanel, 'state');
            app.ButtonMasker20.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonMasker20.Tag = 'Masker20';
            app.ButtonMasker20.Tooltip = {'-75°'};
            app.ButtonMasker20.Text = '';
            app.ButtonMasker8 = uibutton(app.MaskerspeakerPanel, 'state');
            app.ButtonMasker8.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonMasker8.Tag = 'Masker8';
            app.ButtonMasker8.Tooltip = {'+105°'};
            app.ButtonMasker8.Text = '';
            app.ButtonMasker9 = uibutton(app.MaskerspeakerPanel, 'state');
            app.ButtonMasker9.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonMasker9.Tag = 'Masker9';
            app.ButtonMasker9.Tooltip = {'+120°'};
            app.ButtonMasker9.Text = '';
            app.ButtonMasker11 = uibutton(app.MaskerspeakerPanel, 'state');
            app.ButtonMasker11.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonMasker11.Tag = 'Masker11';
            app.ButtonMasker11.Tooltip = {'+150°'};
            app.ButtonMasker11.Text = '';
            app.ButtonMasker12 = uibutton(app.MaskerspeakerPanel, 'state');
            app.ButtonMasker12.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonMasker12.Tag = 'Masker12';
            app.ButtonMasker12.Tooltip = {'+165°'};
            app.ButtonMasker12.Text = '';
            app.ButtonMasker14 = uibutton(app.MaskerspeakerPanel, 'state');
            app.ButtonMasker14.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonMasker14.Tag = 'Masker14';
            app.ButtonMasker14.Tooltip = {'-165°'};
            app.ButtonMasker14.Text = '';
            app.ButtonMasker15 = uibutton(app.MaskerspeakerPanel, 'state');
            app.ButtonMasker15.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonMasker15.Tag = 'Masker15';
            app.ButtonMasker15.Tooltip = {'-150°'};
            app.ButtonMasker15.Text = '';
            app.ButtonMasker17 = uibutton(app.MaskerspeakerPanel, 'state');
            app.ButtonMasker17.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonMasker17.Tag = 'Masker17';
            app.ButtonMasker17.Tooltip = {'-120°'};
            app.ButtonMasker17.Text = '';
            app.ButtonMasker18 = uibutton(app.MaskerspeakerPanel, 'state');
            app.ButtonMasker18.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonMasker18.Tag = 'Masker18';
            app.ButtonMasker18.Tooltip = {'-105°'};
            app.ButtonMasker18.Text = '';
            app.ButtonMasker1 = uibutton(app.MaskerspeakerPanel, 'state');
            app.ButtonMasker1.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonMasker1.Tag = 'Masker1';
            app.ButtonMasker1.Tooltip = {'0°'};
            app.ButtonMasker1.Text = '';
            app.ButtonMasker4 = uibutton(app.MaskerspeakerPanel, 'state');
            app.ButtonMasker4.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonMasker4.Tag = 'Masker4';
            app.ButtonMasker4.Tooltip = {'+45°'};
            app.ButtonMasker4.Text = '';
            app.ButtonMasker7 = uibutton(app.MaskerspeakerPanel, 'state');
            app.ButtonMasker7.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonMasker7.Tag = 'Masker7';
            app.ButtonMasker7.Tooltip = {'+90°'};
            app.ButtonMasker7.Text = '';
            app.ButtonMasker7.Value = true;
            app.ButtonMasker10 = uibutton(app.MaskerspeakerPanel, 'state');
            app.ButtonMasker10.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonMasker10.Tag = 'Masker10';
            app.ButtonMasker10.Tooltip = {'+135°'};
            app.ButtonMasker10.Text = '';
            app.ButtonMasker13 = uibutton(app.MaskerspeakerPanel, 'state');
            app.ButtonMasker13.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonMasker13.Tag = 'Masker13';
            app.ButtonMasker13.Tooltip = {'180°'};
            app.ButtonMasker13.Text = '';
            app.ButtonMasker16 = uibutton(app.MaskerspeakerPanel, 'state');
            app.ButtonMasker16.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonMasker16.Tag = 'Masker16';
            app.ButtonMasker16.Tooltip = {'-135°'};
            app.ButtonMasker16.Text = '';
            app.ButtonMasker19 = uibutton(app.MaskerspeakerPanel, 'state');
            app.ButtonMasker19.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonMasker19.Tag = 'Masker19';
            app.ButtonMasker19.Tooltip = {'-90°'};
            app.ButtonMasker19.Text = '';
            app.ButtonMasker19.Value = true;
            app.ButtonMasker22 = uibutton(app.MaskerspeakerPanel, 'state');
            app.ButtonMasker22.ValueChangedFcn = createCallbackFcn(app, @ButtonSpeakerValueChanged, true);
            app.ButtonMasker22.Tag = 'Masker22';
            app.ButtonMasker22.Tooltip = {'-45°'};
            app.ButtonMasker22.Text = '';
            app.CenterMasker = uilabel(app.MaskerspeakerPanel);
            app.CenterMasker.Text = '△';
            app.SpeakerMasker1 = uieditfield(app.MaskerspeakerPanel, 'numeric');
            app.SpeakerMasker1.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerMasker1.Tag = '1';
            app.SpeakerMasker1.Enable = 'off';
            app.SpeakerMasker1.Value = 1;
            app.SpeakerMasker2 = uieditfield(app.MaskerspeakerPanel, 'numeric');
            app.SpeakerMasker2.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerMasker2.Tag = '2';
            app.SpeakerMasker2.Enable = 'off';
            app.SpeakerMasker2.Value = 2;
            app.SpeakerMasker3 = uieditfield(app.MaskerspeakerPanel, 'numeric');
            app.SpeakerMasker3.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerMasker3.Tag = '3';
            app.SpeakerMasker3.Enable = 'off';
            app.SpeakerMasker3.Value = 3;
            app.SpeakerMasker4 = uieditfield(app.MaskerspeakerPanel, 'numeric');
            app.SpeakerMasker4.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerMasker4.Tag = '4';
            app.SpeakerMasker4.Enable = 'off';
            app.SpeakerMasker4.Value = 4;
            app.SpeakerMasker5 = uieditfield(app.MaskerspeakerPanel, 'numeric');
            app.SpeakerMasker5.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerMasker5.Tag = '5';
            app.SpeakerMasker5.Enable = 'off';
            app.SpeakerMasker5.Value = 5;
            app.SpeakerMasker6 = uieditfield(app.MaskerspeakerPanel, 'numeric');
            app.SpeakerMasker6.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerMasker6.Tag = '6';
            app.SpeakerMasker6.Enable = 'off';
            app.SpeakerMasker6.Value = 6;
            app.SpeakerMasker7 = uieditfield(app.MaskerspeakerPanel, 'numeric');
            app.SpeakerMasker7.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerMasker7.Tag = '7';
            app.SpeakerMasker7.Value = 7;
            app.SpeakerMasker8 = uieditfield(app.MaskerspeakerPanel, 'numeric');
            app.SpeakerMasker8.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerMasker8.Tag = '8';
            app.SpeakerMasker8.Enable = 'off';
            app.SpeakerMasker8.Value = 8;
            app.SpeakerMasker9 = uieditfield(app.MaskerspeakerPanel, 'numeric');
            app.SpeakerMasker9.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerMasker9.Tag = '9';
            app.SpeakerMasker9.Enable = 'off';
            app.SpeakerMasker9.Value = 9;
            app.SpeakerMasker10 = uieditfield(app.MaskerspeakerPanel, 'numeric');
            app.SpeakerMasker10.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerMasker10.Tag = '10';
            app.SpeakerMasker10.Enable = 'off';
            app.SpeakerMasker10.Value = 10;
            app.SpeakerMasker11 = uieditfield(app.MaskerspeakerPanel, 'numeric');
            app.SpeakerMasker11.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerMasker11.Tag = '11';
            app.SpeakerMasker11.Enable = 'off';
            app.SpeakerMasker11.Value = 11;
            app.SpeakerMasker12 = uieditfield(app.MaskerspeakerPanel, 'numeric');
            app.SpeakerMasker12.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerMasker12.Tag = '12';
            app.SpeakerMasker12.Enable = 'off';
            app.SpeakerMasker12.Value = 12;
            app.SpeakerMasker13 = uieditfield(app.MaskerspeakerPanel, 'numeric');
            app.SpeakerMasker13.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerMasker13.Tag = '13';
            app.SpeakerMasker13.Enable = 'off';
            app.SpeakerMasker13.Value = 13;
            app.SpeakerMasker14 = uieditfield(app.MaskerspeakerPanel, 'numeric');
            app.SpeakerMasker14.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerMasker14.Tag = '14';
            app.SpeakerMasker14.Enable = 'off';
            app.SpeakerMasker14.Value = 14;
            app.SpeakerMasker15 = uieditfield(app.MaskerspeakerPanel, 'numeric');
            app.SpeakerMasker15.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerMasker15.Tag = '15';
            app.SpeakerMasker15.Enable = 'off';
            app.SpeakerMasker15.Value = 15;
            app.SpeakerMasker16 = uieditfield(app.MaskerspeakerPanel, 'numeric');
            app.SpeakerMasker16.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerMasker16.Tag = '16';
            app.SpeakerMasker16.Enable = 'off';
            app.SpeakerMasker16.Value = 16;
            app.SpeakerMasker17 = uieditfield(app.MaskerspeakerPanel, 'numeric');
            app.SpeakerMasker17.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerMasker17.Tag = '17';
            app.SpeakerMasker17.Enable = 'off';
            app.SpeakerMasker17.Value = 17;
            app.SpeakerMasker18 = uieditfield(app.MaskerspeakerPanel, 'numeric');
            app.SpeakerMasker18.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerMasker18.Tag = '18';
            app.SpeakerMasker18.Enable = 'off';
            app.SpeakerMasker18.Value = 18;
            app.SpeakerMasker19 = uieditfield(app.MaskerspeakerPanel, 'numeric');
            app.SpeakerMasker19.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerMasker19.Tag = '19';
            app.SpeakerMasker19.Value = 19;
            app.SpeakerMasker20 = uieditfield(app.MaskerspeakerPanel, 'numeric');
            app.SpeakerMasker20.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerMasker20.Tag = '20';
            app.SpeakerMasker20.Enable = 'off';
            app.SpeakerMasker20.Value = 20;
            app.SpeakerMasker21 = uieditfield(app.MaskerspeakerPanel, 'numeric');
            app.SpeakerMasker21.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerMasker21.Tag = '21';
            app.SpeakerMasker21.Enable = 'off';
            app.SpeakerMasker21.Value = 21;
            app.SpeakerMasker22 = uieditfield(app.MaskerspeakerPanel, 'numeric');
            app.SpeakerMasker22.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerMasker22.Tag = '22';
            app.SpeakerMasker22.Enable = 'off';
            app.SpeakerMasker22.Value = 22;
            app.SpeakerMasker23 = uieditfield(app.MaskerspeakerPanel, 'numeric');
            app.SpeakerMasker23.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerMasker23.Tag = '23';
            app.SpeakerMasker23.Enable = 'off';
            app.SpeakerMasker23.Value = 23;
            app.SpeakerMasker24 = uieditfield(app.MaskerspeakerPanel, 'numeric');
            app.SpeakerMasker24.ValueChangedFcn = createCallbackFcn(app, @SpeakerChannelValueChanged, true);
            app.SpeakerMasker24.Tag = '24';
            app.SpeakerMasker24.Enable = 'off';
            app.SpeakerMasker24.Value = 24;
            app.ChannelTitleLabel = uilabel(app.UIFigure);
            app.ChannelTitleLabel.Text = 'Loudspeaker location and channel number specification';
            app.LoadPresetButton = uibutton(app.UIFigure, 'push');
            app.LoadPresetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPresetButtonPushed, true);
            app.LoadPresetButton.Text = 'Load preset';
            app.SavePresetButton = uibutton(app.UIFigure, 'push');
            app.SavePresetButton.ButtonPushedFcn = createCallbackFcn(app, @SavePresetButtonPushed, true);
            app.SavePresetButton.Text = 'Save preset';
            app.ApplyConfigurationButton = uibutton(app.UIFigure, 'push');
            app.ApplyConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyConfigurationButtonPushed, true);
            app.ApplyConfigurationButton.Text = 'Apply';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CloseButton.Text = 'Close';
            app.UploadedPresetEditField = uieditfield(app.UIFigure, 'text');
            app.UploadedPresetEditField.Editable = 'off';
            app.ChannelsTargetEditFieldLabel = uilabel(app.UIFigure);
            app.ChannelsTargetEditFieldLabel.Text = 'Channel(s) target';
            app.ChannelsTargetEditField = uieditfield(app.UIFigure, 'text');
            app.ChannelsTargetEditField.Editable = 'off';
            app.ChannelsTargetEditField.Tooltip = {'Specify the channel numbers for the target. Single and multiple values are accepted.'};
            app.ChannelsTargetEditField.Value = '1';
            app.ChannelsMaskerEditFieldLabel = uilabel(app.UIFigure);
            app.ChannelsMaskerEditFieldLabel.Text = 'Channel(s) masker';
            app.ChannelsMaskerEditField = uieditfield(app.UIFigure, 'text');
            app.ChannelsMaskerEditField.Editable = 'off';
            app.ChannelsMaskerEditField.Tooltip = {'Specify the channel numbers for the masker. Single and multiple values are accepted.'};
            app.ChannelsMaskerEditField.Value = '7 19';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DASTchannels(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end