classdef DASTsettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UploadedPresetEditField         matlab.ui.control.EditField
        LoadPresetButton                matlab.ui.control.Button
        ApplySettingsButton             matlab.ui.control.Button
        SavePresetButton                matlab.ui.control.Button
        MaskerButtonGroup               matlab.ui.container.ButtonGroup
        DurationbeforeEditField         matlab.ui.control.NumericEditField
        DurationbeforeEditFieldLabel    matlab.ui.control.Label
        SecLabel                        matlab.ui.control.Label
        IntermittentButton              matlab.ui.control.RadioButton
        ContinuousButton                matlab.ui.control.RadioButton
        MiscellaneousButtonGroup        matlab.ui.container.ButtonGroup
        AudiogramFileEditField          matlab.ui.control.EditField
        AudiogramFileSelectButton       matlab.ui.control.Button
        AudiogramFileLabel              matlab.ui.control.Label
        AdjustSentenceLevelRMSCheckbox  matlab.ui.control.CheckBox
        RandomizeOrderWithinListsCheckbox  matlab.ui.control.CheckBox
        ApplyHearingLossCompensationCheckbox  matlab.ui.control.CheckBox
        CalibrationButtonGroup          matlab.ui.container.ButtonGroup
        StartCreateFilterButton         matlab.ui.control.Button
        CreateFilterEditFieldLabel      matlab.ui.control.Label
        CalibrationEditFieldLabel       matlab.ui.control.Label
        LeftMeasuredEditFieldLabel      matlab.ui.control.Label
        LeftMeasuredEditField           matlab.ui.control.NumericEditField
        RightMeasuredEditField          matlab.ui.control.NumericEditField
        RightMeasuredEditFieldLabel     matlab.ui.control.Label
        SelectEqualizationFilterButton  matlab.ui.control.Button
        EqualizationFilterEditField     matlab.ui.control.EditField
        EqualizationfilterEditFieldLabel  matlab.ui.control.Label
        PlayCalibrationFileButton       matlab.ui.control.StateButton
        MeasuredLabel                   matlab.ui.control.Label
        dBZLabel                        matlab.ui.control.Label
        PlaybackmodeButtonGroup         matlab.ui.container.ButtonGroup
        MultiButton                     matlab.ui.control.RadioButton
        MonoButton                      matlab.ui.control.RadioButton
        StereoButton                    matlab.ui.control.RadioButton
        PlaybackMethodButtonGroup       matlab.ui.container.ButtonGroup
        ChannelsInEditField             matlab.ui.control.EditField
        ChannelsInEditFieldLabel        matlab.ui.control.Label
        BasicButton                     matlab.ui.control.RadioButton
        AudioToolboxButton              matlab.ui.control.RadioButton
        PlayrecButton                   matlab.ui.control.RadioButton
        DriverDropDownLabel             matlab.ui.control.Label
        DriverDropDown                  matlab.ui.control.DropDown
        ChannelsOutEditField            matlab.ui.control.EditField
        ChannelsOutEditFieldLabel       matlab.ui.control.Label
        PresentationModeButtonGroup     matlab.ui.container.ButtonGroup
        CurrentAudioDir                 matlab.ui.control.EditField
        PathtofilesLabel_2              matlab.ui.control.Label
        SelectVideoDirButton            matlab.ui.control.Button
        CurrentVideoDir                 matlab.ui.control.EditField
        PathtofilesLabel                matlab.ui.control.Label
        SelectAudioDirButton            matlab.ui.control.Button
        AudioVisualPresentationButton   matlab.ui.control.RadioButton
        AudioPresentationButton         matlab.ui.control.RadioButton
        ScoringTypeButtonGroup          matlab.ui.container.ButtonGroup
        WordByWordButton                matlab.ui.control.RadioButton
        KeywordBasedButton              matlab.ui.control.RadioButton
        ResultTypeButtonGroup           matlab.ui.container.ButtonGroup
        StoppingCriteriaEditField       matlab.ui.control.NumericEditField
        StoppingCriteriaLabel           matlab.ui.control.Label
        StoppingCriteriaDecibelLabel    matlab.ui.control.Label
        AdaptiveMethodDropdownLabel     matlab.ui.control.Label
        AdaptiveMethodDropdown          matlab.ui.control.DropDown
        SpeechReceptionThresholdButton  matlab.ui.control.RadioButton
        PercentageCorrectButton         matlab.ui.control.RadioButton
    end


    properties (Access = private)
        CallingApp % main app
    end

    properties (Access = public)
        % % Definition of global variables

        % Everything that gets saved in result file (i.e., DATA)
        data

        % Everything that doesnt get saved but persists throughout the session (i.e., PERSISTENT)
        pers

        % Everything that changes from sentence to sentence (i.e., ITERATION)
        iter
    end

    methods (Access = public)

        function readSettingsFromInterface(app)

            % Result type
            if app.PercentageCorrectButton.Value == 1
                app.data.Settings.ResultType = "Percent correct";
            else
                app.data.Settings.ResultType = "Speech reception threshold";
                app.data.Settings.AdaptiveMethod = app.AdaptiveMethodDropdown.Value;
                app.data.Settings.StoppingCriteria = app.StoppingCriteriaEditField.Value;
            end

            % Scoring type
            if app.WordByWordButton.Value == 1
                app.data.Settings.ScoringType = "Word-by-word";
            else
                app.data.Settings.ScoringType = "Keyword-based";
            end

            % Presentation mode
            if app.AudioPresentationButton.Value == 1
                app.data.Settings.PresentationMode = "Audio";
            else
                app.data.Settings.PresentationMode = "Audio-visual";
            end
            app.data.Settings.AudioPath = app.CurrentAudioDir.Value;
            app.data.Settings.VideoPath = app.CurrentVideoDir.Value;

            % Masker
            if app.IntermittentButton.Value == 1
                app.data.Settings.MaskerSettings = "Intermittent";
                app.data.Settings.DurationBefore = app.DurationbeforeEditField.Value;
            else
                app.data.Settings.MaskerSettings = "Continuous";
            end

            % Playback mode
            if app.MonoButton.Value == 1
                app.data.Settings.PlaybackMode = "Mono";
            elseif app.StereoButton.Value == 1
                app.data.Settings.PlaybackMode = "Stereo";
            else
                app.data.Settings.PlaybackMode = "Multi";
            end

            % Calibration
            app.data.Settings.dBSPLRef = 95; % 95 dB SPL is equivalent to -26 dB FS
            app.data.Settings.dBFSRef = -26;
            app.data.Settings.hannLen = 50e-3*48e3; % 50ms of ramping up and down of the masker (i.e., based on 48 kHz sampling freq)
            app.data.Settings.hannWindow = hann(2*50e-3*48e3);
            app.data.Settings.Fs = 48e3; % Fs = 48 kHz
            app.data.Settings.EqualizationFilterPath = app.EqualizationFilterEditField.Value;
            app.data.Settings.CalibrationMeasuredLevel = ...
                [app.LeftMeasuredEditField.Value app.RightMeasuredEditField.Value];

            % Miscellaneous
            app.data.Settings.AdjustSentenceLevelRMS = app.AdjustSentenceLevelRMSCheckbox.Value;
            app.data.Settings.RandomizeOrderWithinLists = app.RandomizeOrderWithinListsCheckbox.Value;
            app.data.Settings.ApplyHearingLossCompensation = app.ApplyHearingLossCompensationCheckbox.Value;
            app.data.Settings.AudiogramFilePath = app.AudiogramFileEditField.Value;

            % Playback method
            % Grabs default settings from "Settings" tab
            if ispc % if windows
                app.DriverDropDown.Items = {'ASIO', 'DirectSound', 'WASAPI'};
            elseif ismac % if mac
                app.DriverDropDown.Items = {'CoreAudio'};
            else % if linux (isunix)
                app.DriverDropDown.Items = {'ALSA'};
            end

            if app.BasicButton.Value == 1
                app.data.Settings.PlaybackMethod = "Basic";
                app.data.Settings.PlaybackDriver = [];
                app.data.Settings.PlaybackChannelsOut = [];
                app.data.Settings.PlaybackChannelsIn = [];
            elseif app.AudioToolboxButton.Value == 1
                app.data.Settings.PlaybackMethod = "AudioToolbox";
                app.data.Settings.PlaybackDriver = app.DriverDropDown.Value;
                app.data.Settings.PlaybackChannelsOut = str2num(string(app.ChannelsOutEditField.Value)); %#ok<ST2NM>
                app.data.Settings.PlaybackChannelsIn = str2num(string(app.ChannelsInEditField.Value)); %#ok<ST2NM>
                app.data.Settings.PlaybackBuffer = 2^14; % hard-coded
            else
                app.data.Settings.PlaybackMethod = "Playrec";
                app.data.Settings.PlaybackDriver = [];
                app.data.Settings.PlaybackChannelsOut = str2num(string(app.ChannelsOutEditField.Value)); %#ok<ST2NM>
                app.data.Settings.PlaybackChannelsIn = str2num(string(app.ChannelsInEditField.Value)); %#ok<ST2NM>
            end
            initializePlaybackObjects(app);

            % Update current preset name
            app.pers.UploadedPreset = app.UploadedPresetEditField.Value;

        end

        function writeSettingsToInterface(app)

            % Result type
            if app.data.Settings.ResultType == "Percent correct"
                app.PercentageCorrectButton.Value = 1;
            else
                app.SpeechReceptionThresholdButton.Value = 1;
                app.AdaptiveMethodDropdown.Value = app.data.Settings.AdaptiveMethod;
                app.StoppingCriteriaEditField.Value = app.data.Settings.StoppingCriteria;
            end

            % Scoring type
            if app.data.Settings.ScoringType == "Word-by-word"
                app.WordByWordButton.Value = 1;
            else
                app.KeywordBasedButton.Value = 1;
            end

            % Presentation mode
            if app.data.Settings.PresentationMode == "Audio"
                app.AudioPresentationButton.Value = 1;
            else
                app.AudioVisualPresentationButton.Value = 1;
            end
            app.CurrentAudioDir.Value = app.data.Settings.AudioPath;
            app.CurrentVideoDir.Value = app.data.Settings.VideoPath;

            % Masker
            if app.data.Settings.MaskerSettings == "Intermittent"
                app.IntermittentButton.Value = 1;
                app.DurationbeforeEditField.Value = app.data.Settings.DurationBefore;
            else
                app.ContinuousButton.Value = 1;
            end

            % Playback mode
            if app.data.Settings.PlaybackMode == "Mono"
                app.MonoButton.Value = 1;
            elseif app.data.Settings.PlaybackMode == "Stereo"
                app.StereoButton.Value = 1;
            else
                app.MultiButton.Value = 1;
            end

            % Calibration
            app.EqualizationFilterEditField.Value = app.data.Settings.EqualizationFilterPath;
            app.LeftMeasuredEditField.Value = app.data.Settings.CalibrationMeasuredLevel(1);
            app.RightMeasuredEditField.Value = app.data.Settings.CalibrationMeasuredLevel(2);

            % Miscellaneous
            app.AdjustSentenceLevelRMSCheckbox.Value = app.data.Settings.AdjustSentenceLevelRMS;
            app.RandomizeOrderWithinListsCheckbox.Value = app.data.Settings.RandomizeOrderWithinLists;
            app.ApplyHearingLossCompensationCheckbox.Value = app.data.Settings.ApplyHearingLossCompensation;
            app.AudiogramFileEditField.Value = app.data.Settings.AudiogramFilePath;

            % Playback method
            if app.data.Settings.PlaybackMethod == "Basic"
                app.BasicButton.Value = 1;
                app.DriverDropDown.Enable = "off";
                app.ChannelsOutEditField.Value = '';
                app.ChannelsInEditField.Value = '';
            elseif app.data.Settings.PlaybackMethod == "AudioToolbox"
                app.AudioToolboxButton.Value = 1;
                app.DriverDropDown.Value = app.data.Settings.PlaybackDriver;
                app.ChannelsOutEditField.Value = num2str(app.data.Settings.PlaybackChannelsOut);
                app.ChannelsInEditField.Value = num2str(app.data.Settings.PlaybackChannelsIn);
            else
                app.PlayrecButton.Value = 1;
                app.DriverDropDown.Enable = "off";
                app.ChannelsOutEditField.Value = num2str(app.data.Settings.PlaybackChannelsOut);
                app.ChannelsInEditField.Value = num2str(app.data.Settings.PlaybackChannelsIn);
            end
            initializePlaybackObjects(app);

            % Call selection change relevant callback functions
            ResultTypeButtonGroupSelectionChanged(app);
            PresentationModeButtonGroupSelectionChanged(app);
            MaskerButtonGroupSelectionChanged(app);
            PlaybackMethodButtonGroupSelectionChanged(app);

            % Write name of current preset
            app.UploadedPresetEditField.Value = app.pers.UploadedPreset;

            % Force update
            drawnow;
            figure(app.UIFigure);

        end

        function initializePlaybackObjects(app)

            % Setting up playback method
            switch app.data.Settings.PlaybackMethod

                case 'Basic'

                    % No setup needed

                case 'AudioToolbox' % Matlab Audio Toolbox

                    % Check if dsp object already exists
                    if ~isempty(app.pers.playbackObjectPlayer)
                        release(app.pers.playbackObjectPlayer);
                    end
                    if ~isempty(app.pers.playbackObjectRecorder)
                        release(app.pers.playbackObjectRecorder);
                    end

                    % Create the handle to the device writer
                    if ~isempty(app.data.Settings.PlaybackChannelsOut)
                        app.pers.playbackObjectPlayer = audioDeviceWriter(app.data.Settings.Fs,...
                            'Driver',app.data.Settings.PlaybackDriver,...
                            'ChannelMappingSource','Property',...
                            'ChannelMapping',app.data.Settings.PlaybackChannelsOut,...
                            'BufferSize',app.data.Settings.PlaybackBuffer);
                    end

                    % Create the handle to the device recorder, if relevant
                    if ~isempty(app.data.Settings.PlaybackChannelsIn)
                        app.pers.playbackObjectRecorder = audioDeviceReader(app.data.Settings.Fs,...
                            'Driver',app.data.Settings.PlaybackDriver,...
                            'ChannelMappingSource','Property',...
                            'ChannelMapping',app.data.Settings.PlaybackChannelsIn,...
                            'SamplesPerFrame',app.data.Settings.PlaybackBuffer);
                    end

                case 'Playrec'

                    % Create the handle
                    HeaAudioPlayRec(...
                        'fs',app.data.Settings.Fs,...
                        'channelsPlay',app.data.Settings.PlaybackChannelsOut,...
                        'channelsRec',app.data.Settings.PlaybackChannelsIn,...
                        'devicePlayID',app.data.Settings.PlaybackDriver);

                    % TODO Implement recording capability
            end

        end

        function releaseAudioObjs(app)

            % Release all existing audio objects
            clear sound
            app.iter.recordingFlag = false;
            app.iter.playingFlag = false;
            if isfield(app.data.Settings,'playbackObjectPlayer') && ~isempty(app.pers.playbackObjectPlayer)
                release(app.pers.playbackObjectPlayer);
            end
            if isfield(app.data.Settings,'playbackObjectRecorder') && ~isempty(app.pers.playbackObjectRecorder)
                release(app.pers.playbackObjectRecorder);
            end
            if isfield(app.data.Settings,'playbackObjectFileWriter') && ~isempty(app.pers.playbackObjectFileWriter)
                release(app.pers.playbackObjectFileWriter);
            end

        end

        function playSignal(app,signalToPlay)

            % If in Basic mode, play using sound function and return
            if strcmp(app.data.Settings.PlaybackMethod,'Basic')

                sound(signalToPlay,app.data.Settings.Fs);
                pause(length(signalToPlay)/app.data.Settings.Fs);
                if app.data.Settings.PresentationMode == "Audio-visual"
                    warning('Audiovisual presentation is not possible using basic playback.');
                end
                return;

            end

            % Initialize visual part of target stimuli, if relevant
            if app.data.Settings.PresentationMode == "Audio-visual"
                initializeVisualPartOfStimuli(app);
            end

            % Duplicate signalToPlay, if relevant
            signalToPlay = duplicateAcrossChannels(app,signalToPlay);

            % Start acoustic stimuli if using non-blocking playbackMethod
            if strcmp(app.data.Settings.PlaybackMethod,'Playrec')

                % Send signal via playrec
                playrec('play',signalToPlay,app.data.Settings.PlaybackChannelsOut);

                % Pause to align audio and video, if relevant
                if app.data.Settings.PresentationMode == "Audio-visual" && app.data.Settings.DurationBefore > 0.5
                    pause(app.data.Settings.DurationBefore-0.5);
                end

            end

            % Loop through the stimuli for block-based playback
            app.iter.playingFlag = true;
            app.iter.playingIdx = 1;
            while app.iter.playingFlag==true && app.iter.playingIdx<size(signalToPlay,1)

                % Send the acoustic part of the buffer, if relevant
                if strcmp(app.data.Settings.PlaybackMethod,'AudioToolbox')
                    playAcousticPartOfBuffer(app,signalToPlay)
                end

                % Send the visual part of the buffer, if relevant
                if app.data.Settings.PresentationMode == "Audio-visual"
                    playVisualPartOfBuffer(app);
                end

                % Drawnow and update buffer index
                drawnow;
                app.iter.playingIdx = app.iter.playingIdx+app.data.Settings.PlaybackBuffer;

            end

            % Conclude visual part of stimuli, if relevant
            if app.data.Settings.PresentationMode == "Audio-visual"
                concludeVisualPartOfStimuli(app);
            end

            % Wait until sentence is complete
            pause(length(signalToPlay)/app.data.Settings.Fs);

            % Release audio objects
            releaseAudioObjs(app);

        end

        function playAcousticPartOfBuffer(app,signalToPlay)

            % Buffer
            if (app.iter.playingIdx+app.data.Settings.PlaybackBuffer-1)<size(signalToPlay,1)
                signalToPlayBuffered = signalToPlay(app.iter.playingIdx:(app.iter.playingIdx+app.data.Settings.PlaybackBuffer-1),:);
            else
                zeroPadLen = app.data.Settings.PlaybackBuffer-size(signalToPlay(app.iter.playingIdx:end,:),1);
                signalToPlayBuffered = [signalToPlay(app.iter.playingIdx:end,:);
                    zeros(zeroPadLen,size(signalToPlay,2))];
            end

            % Send via playback object
            app.pers.playbackObjectPlayer(signalToPlayBuffered);

        end

        function playVisualPartOfBuffer(app)

            % Wait for next movie frame, retrieve texture handle to it
            tex = Screen('GetMovieImage', app.iter.video.win, app.iter.video.movie);

            % Break if movie is done (i.e., if texture is invalid)
            if tex <= 0
                return;
            end

            % Draw the new texture immediately to screen
            Screen('DrawTexture', app.iter.video.win, tex);

            % Update the display
            Screen('Flip', app.iter.video.win, 0);

            % Release the texture
            Screen('Close', tex);

        end


        function concludeVisualPartOfStimuli(app)

            % Stop playback
            Screen('PlayMovie', app.iter.video.movie, 0);

            % Close movie
            Screen('CloseMovie', app.iter.video.movie);
            Screen('DrawTexture', app.iter.video.win, app.iter.video.imageTexture);
            Screen('Flip', app.iter.video.win,[], 1);
            if app.pers.sentIdxInBlock == app.iter.sentTotalInBlock
                Screen('MakeTexture', app.iter.video.win, app.pers.greyImage);
                Screen('Flip',app.iter.video.win,[],1);
            end
            FlushEvents();

        end

        function signal = replicateForStereoOrMulti(app,signal)

            % Replicate vectors as needed
            switch app.data.Settings.PlaybackMode
                case 'Mono'
                    % do nothing
                case 'Stereo'
                    signal = repmat(signal,1,2);
                    %                 case 'Multi'
                    %                     signal = repmat(signal,1,TODO);
            end

        end

        function signal = applyEqualizationFilter(app,signal)

            % Load equalization filter, if relevant
            if ~isempty(app.data.Settings.EqualizationFilterPath) && ...
                    ~isfield(app.pers,'equalizationFilter')
                load(app.data.Settings.EqualizationFilterPath);
                app.pers.equalizationFilter = equalizationFilter;
            elseif isempty(app.data.Settings.EqualizationFilterPath)
                app.pers.equalizationFilter = [];
            end

            % Apply equalization filter, if relevant
            if ~isempty(app.pers.equalizationFilter)
                for cc = 1:size(app.pers.equalizationFilter,2)
                    signal(:,cc) = filter(app.pers.equalizationFilter(:,cc),1,signal(:,cc));
                end
            end

        end

        function signal = scaleToDesiredLevel(app,signal,level)

            % NOTE Assumes signal has a long-term RMS scaled to -26 dB FS

            % Scale the digital levels in each channel to get the desired physical level
            for cc = 1:size(signal,2)

                % Calculate gain (i.e., adjust for desired level correction + calibration offset)
                gain = (level-app.data.Settings.dBSPLRef) + ...
                    (app.data.Settings.dBSPLRef-app.data.Settings.CalibrationMeasuredLevel(cc));
                signal(:,cc) = signal(:,cc)*(10^(gain/20));

            end

        end

        function yi = loginterp(~,x,y,xi)

            yi = zeros(size(xi));
            for ni=1:length(xi)
                if xi(ni)<=x(1)
                    yi(ni)=y(1);
                elseif xi(ni)>=x(end)
                    yi(ni)=y(end);
                else
                    ns = find(x>xi(ni));
                    n = ns(1);
                    yi(ni) = y(n) + (y(n-1) - y(n))*log10(xi(ni)/x(n))/log10(x(n-1)/x(n));
                end
            end

        end

        function signal = duplicateAcrossChannels(app,signal)

            if size(signal,2)~=length(app.data.Settings.PlaybackChannelsOut)
                switch app.data.Settings.PlaybackMode
                    case 'Mono'
                        signal = repmat(signal, 1, ...
                            length(app.data.Settings.PlaybackChannelsOut));
                    otherwise
                        if mod(length(app.data.Settings.PlaybackChannelsOut),size(signal,2))==0 % duplicate
                            signal = repmat(signal, 1, ...
                                length(app.data.Settings.PlaybackChannelsOut)/size(signal,2));
                        end
                end
            end

        end

        function [H,h] = truncateAndNormalizeImpulseResponse(app,IR)

            % Cutting the "important" part out of the impulse response

            % Parameters
            t1 = 2; % desired predelay before main peak in truncated impulse response 'h' (ms)
            t2 = 98; % desired decay time after main peak in 'h' (ms)
            refFreq = 1000; % frequency used as a reference (Hz)

            % Truncate
            [~,k] = max(abs(IR));
            n1 = k-round(t1*app.data.Settings.Fs/1000);
            if n1 < 1
                n1 = 1;
            end
            n2 = k+round(t2*app.data.Settings.Fs/1000);
            if n2 > length(IR)
                n2 = length(IR);
            end
            h = IR(n1:n2);

            % Force an impulse response with an even number of samples
            if rem(length(h),2) ~= 0
                h = h(1:end-1);
            end

            % Initialize frequency vector from 0...1, as needed for filter design later on
            N = length(h)/2;
            fNorm = linspace(0,1,N)';

            % Compute one-sided FFT spectrum, normalize, and invert
            H = fft(h);
            v = abs(fNorm*app.data.Settings.Fs/2-refFreq);
            H = H / abs(H(v==min(v))); % normalizing H
            h = ifft(H);
            H = H(1:N);

        end

        function equalizationFilter = createEqualizationFilter(app,impulseResponse)

            % Parameters
            filterLength = 2048; % desired length of inverse filter (samples)
            lowDropFreq = 50; % lower roll-off frequency (below this frequency the spectrum will be set constant)
            highDropFreq = 15000; % upper roll-off frequency (above this frequency the spectrum will be set constant)

            % Compute FFT
            n = length(impulseResponse);
            H = fft(impulseResponse,n);

            % Get number of unique frequency bins
            K = (n+bitand(n,1))/2+~bitand(n,1);

            % Return positive frequencies
            H = abs(H(1:K));

            % Remove the frequency roll-off for very low and very high frequencies
            N = size(H,1);
            n1 = round(lowDropFreq/app.data.Settings.Fs*2*N+1);
            n2 = round(highDropFreq/app.data.Settings.Fs*2*N+1);
            H(1:n1) = H(n1);
            H(n2:N) = H(n2);

            % Normalized frequency
            fNorm = linspace(0,1,length(H))';

            % Calculate inverse filter
            Bi = fir2(filterLength,fNorm,1./H); % minimum phase
            [~,hinv] = rceps(Bi);
            hinv = hinv(1:filterLength);
            equalizationFilter = hinv(:);

        end


        function selectionChangedState(app)
            % If any of the selection is changed in the window
            app.ApplySettingsButton.Enable = "on";
            app.SavePresetButton.Enable = "on";
            app.UploadedPresetEditField.Value = '';
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, data, pers)

            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;

            % Intialize variables
            app.data = data; % everything that gets saved in result file (i.e., DATA)
            app.pers = pers; % everything that doesnt get saved but persists throughout the session (i.e., PERSISTENT)

            % dummy for calibration
            app.iter = [];

            % Audio and video directories
            app.CurrentAudioDir.Value = app.data.Settings.AudioPath;
            app.CurrentVideoDir.Value = app.data.Settings.VideoPath;

            % Grabs default settings from "Settings" tab
            if ispc % if windows
                app.DriverDropDown.Items = {'ASIO', 'DirectSound', 'WASAPI'};
            elseif ismac % if mac
                app.DriverDropDown.Items = {'CoreAudio'};
            else % if linux (isunix)
                app.DriverDropDown.Items = {'ALSA'};
            end

            % Updating workspace settings with current variable, if exists
            if isfield(app.data.Settings,'ResultType')
                writeSettingsToInterface(app)
            end

            % Disable buttons
            app.ApplySettingsButton.Enable = "off";
            app.SavePresetButton.Enable = "off";
        end

        % Selection changed function: ResultTypeButtonGroup
        function ResultTypeButtonGroupSelectionChanged(app, event)

            % Changed selection: enable apply/save buttons, del preset name
            selectionChangedState(app)

            % Handle radio buttons
            if app.PercentageCorrectButton.Value == 1
                app.AdaptiveMethodDropdownLabel.Enable = "off";
                app.AdaptiveMethodDropdown.Enable = "off";
                app.StoppingCriteriaLabel.Enable = "off";
                app.StoppingCriteriaEditField.Enable = "off";
                app.StoppingCriteriaDecibelLabel.Enable = "off";

            else
                app.AdaptiveMethodDropdownLabel.Enable = "on";
                app.AdaptiveMethodDropdown.Enable = "on";
                app.StoppingCriteriaLabel.Enable = "on";
                app.StoppingCriteriaEditField.Enable = "on";
                app.StoppingCriteriaDecibelLabel.Enable = "on";
            end

        end

        % Selection changed function: ScoringTypeButtonGroup
        function ScoringTypeButtonGroupSelectionChanged(app, event)
            % Changed selection: enable apply/save buttons, del preset name
            selectionChangedState(app)
        end

        % Selection changed function: PresentationModeButtonGroup
        function PresentationModeButtonGroupSelectionChanged(app, event)
            % Changed selection: enable apply/save buttons, del preset name
            selectionChangedState(app)
            if app.AudioPresentationButton.Value == 1
                app.CurrentVideoDir.Enable = "off";
                app.PathtofilesLabel.Enable = "off";
                app.SelectVideoDirButton.Enable = "off";
            else
                app.CurrentVideoDir.Enable = "on";
                app.PathtofilesLabel.Enable = "on";
                app.SelectVideoDirButton.Enable = "on";
            end
        end

        % Selection changed function: MaskerButtonGroup
        function MaskerButtonGroupSelectionChanged(app, event)
            % Changed selection: enable apply/save buttons, del preset name
            selectionChangedState(app)
            if app.IntermittentButton.Value == 1
                app.DurationbeforeEditField.Enable = "on";
                app.DurationbeforeEditFieldLabel.Enable = "on";
                app.SecLabel.Enable = "on";
            else
                app.DurationbeforeEditField.Enable = "off";
                app.DurationbeforeEditFieldLabel.Enable = "off";
                app.SecLabel.Enable = "off";
            end
        end

        % Selection changed function: PlaybackmodeButtonGroup
        function PlaybackmodeButtonGroupSelectionChanged(app, event)
            % Changed selection: enable apply/save buttons, del preset name
            selectionChangedState(app)
        end

        % Value changed function: StoppingCriteriaEditField
        function StoppingCriteriaEditFieldValueChanged(app, event)
            % Changed selection: enable apply/save buttons, del preset name
            selectionChangedState(app)
        end

        % Value changed function: DurationbeforeEditField
        function DurationbeforeEditFieldValueChanged(app, event)
            % Changed selection: enable apply/save buttons, del preset name
            selectionChangedState(app)
        end

        % Selection changed function: CalibrationButtonGroup
        function CalibrationButtonGroupSelectionChanged(app, event)
            % Changed selection: enable apply/save buttons, del preset name
            selectionChangedState(app)
        end

        % Value changed function: LeftMeasuredEditField
        function LeftMeasuredEditFieldValueChanged(app, event)
            % Changed selection: enable apply/save buttons, del preset name
            selectionChangedState(app)
        end

        % Value changed function: RightMeasuredEditField
        function RightMeasuredEditFieldValueChanged(app, event)
            % Changed selection: enable apply/save buttons, del preset name
            selectionChangedState(app)
        end

        % Value changed function: AdjustSentenceLevelRMSCheckbox
        function AdjustSentenceLevelRMSCheckboxValueChanged(app, event)
            % Changed selection: enable apply/save buttons, del preset name
            selectionChangedState(app)
        end

        % Value changed function: RandomizeOrderWithinListsCheckbox
        function RandomizeOrderWithinListsCheckboxValueChanged(app, event)
            % Changed selection: enable apply/save buttons, del preset name
            selectionChangedState(app)
        end

        % Value changed function: ApplyHearingLossCompensationCheckbox
        function ApplyHearingLossCompensationCheckboxValueChanged(app, event)
            % Changed selection: enable apply/save buttons, del preset name
            selectionChangedState(app)
        end

        % Button pushed function: AudiogramFileSelectButton
        function AudiogramFileSelectButtonPushed(app, event)
            [filename,pathname] = uigetfile('*.csv','Select audiogram file');
            if filename==0 % user pressed cancel
                drawnow;
                figure(app.UIFigure);
                app.AudiogramFileEditField.Value = '';
                return;
            else
                app.AudiogramFileEditField.Value = fullfile(pathname,filename);
                drawnow;
                figure(app.UIFigure);
                % Changed selection: enable apply/save buttons, del preset name
                selectionChangedState(app)
            end
        end

        % Selection changed function: PlaybackMethodButtonGroup
        function PlaybackMethodButtonGroupSelectionChanged(app, event)
            % Changed selection: enable apply/save buttons, del preset name
            selectionChangedState(app)
            % Handle radio buttons
            if app.BasicButton.Value == 1
                app.DriverDropDown.Enable = "off";
                app.DriverDropDownLabel.Enable = "off";
                app.ChannelsOutEditField.Enable = "off";
                app.ChannelsOutEditFieldLabel.Enable = "off";
                app.ChannelsInEditField.Enable = "off";
                app.ChannelsInEditFieldLabel.Enable = "off";
            elseif app.AudioToolboxButton.Value == 1
                app.DriverDropDown.Enable = "on";
                app.DriverDropDownLabel.Enable = "on";
                app.ChannelsOutEditField.Enable = "on";
                app.ChannelsOutEditFieldLabel.Enable = "on";
                app.ChannelsInEditField.Enable = "on";
                app.ChannelsInEditFieldLabel.Enable = "on";
            else
                app.DriverDropDown.Enable = "off";
                app.DriverDropDownLabel.Enable = "off";
                app.ChannelsOutEditField.Enable = "on";
                app.ChannelsOutEditFieldLabel.Enable = "on";
                app.ChannelsInEditField.Enable = "on";
                app.ChannelsInEditFieldLabel.Enable = "on";
            end
        end

        % Value changed function: ChannelsOutEditField
        function ChannelsOutEditFieldValueChanged(app, event)
            % Changed selection: enable apply/save buttons, del preset name
            selectionChangedState(app)
        end

        % Value changed function: ChannelsInEditField
        function ChannelsInEditFieldValueChanged(app, event)
            % Changed selection: enable apply/save buttons, del preset name
            selectionChangedState(app)
        end

        % Button pushed function: StartCreateFilterButton
        function StartCreateFilterButtonPushed(app, event)
            % Initialize object (note uses Audio Toolbox regardless of selected playback method)
            playerRecorderObject = audioPlayerRecorder(...
                'Device','ASIO Fireface USB',...
                'SampleRate',app.data.Settings.Fs,...
                'BitDepth','24-bit integer',...
                'PlayerChannelMapping',[],...
                'RecorderChannelMapping',app.data.Settings.PlaybackChannelsIn);

            % Create excitation signal
            duration = 10; % seconds
            endSilence = 300e-3; % seconds
            fStart = 80; % Hz
            fStop = 20000; % Hz
            excitationSignal = sweeptone(duration, endSilence, app.data.Settings.Fs, ...
                'ExcitationLevel', app.data.Settings.dBFSRef, 'SweepFrequencyRange', [fStart fStop]);

            % Initialize variables
            dataLength = size(excitationSignal,1);
            frameLength = 2048;
            numFrames = ceil(dataLength/frameLength);
            numIterationsPerChannel = 3;
            recordedSweep = zeros(dataLength,...
                length(app.data.Settings.PlaybackChannelsOut),...
                numIterationsPerChannel);
            impulseResponseEstimate = cell(...
                length(app.data.Settings.PlaybackChannelsOut),...
                numIterationsPerChannel);

            % Loop through each output channel
            for cc = 1:length(app.data.Settings.PlaybackChannelsOut)

                for ii = 1:numIterationsPerChannel

                    % Instruct to place headphone onto microphone
                    hWarn = warndlg(['Prepare to measure Channel ' ...
                        num2str(app.data.Settings.PlaybackChannelsOut(cc)) ...
                        ' (Iteration ' num2str(ii) '/' num2str(numIterationsPerChannel) ')'],...
                        'Create equalization filter');
                    waitfor(hWarn);

                    % Set the playback channel
                    playerRecorderObject.release;
                    playerRecorderObject.PlayerChannelMapping = app.data.Settings.PlaybackChannelsOut(cc);

                    % Play and record
                    nUnderruns = zeros(numFrames,1);
                    nOverruns = zeros(numFrames,1);
                    for nn = 1:numFrames-1
                        start = frameLength*(nn-1)+1;
                        stop = frameLength*nn;
                        [recordedSweep(start:stop,cc,ii),nUnderruns(nn),nOverruns(nn)] = ...
                            playerRecorderObject(excitationSignal(start:stop));
                    end
                    playerRecorderObject.release;

                    % Estimate impulse responses
                    impulseResponseEstimate{cc,ii} = impzest(excitationSignal,...
                        squeeze(recordedSweep(:,cc,ii)));

                end

            end

            % Create the inverse filter
            equalizationFilter = nan(2048,...
                length(app.data.Settings.PlaybackChannelsOut));
            for cc = 1:length(app.data.Settings.PlaybackChannelsOut)

                % Calculate the mean impulse response over the iterations
                impulseResponseMean = mean(cell2mat(impulseResponseEstimate(cc,:)),2);

                % Truncate and normalize
                [~,impulseResponseMean] = truncateAndNormalizeImpulseResponse(...
                    app,impulseResponseMean);

                % Create the inverse equalization filter
                equalizationFilter(:,cc) = createEqualizationFilter(app,...
                    impulseResponseMean);

            end

            % Save
            uisave('equalizationFilter');
        end

        % Value changed function: DriverDropDown
        function DriverDropDownValueChanged(app, event)
            % Changed selection: enable apply/save buttons, del preset name
            selectionChangedState(app)
        end

        % Value changed function: EqualizationFilterEditField
        function EqualizationFilterEditFieldValueChanged(app, event)
            % Changed selection: enable apply/save buttons, del preset name
            selectionChangedState(app)
        end

        % Value changed function: CurrentAudioDir
        function CurrentAudioDirValueChanged(app, event)
            % Changed selection: enable apply/save buttons, del preset name
            selectionChangedState(app)
        end

        % Value changed function: CurrentVideoDir
        function CurrentVideoDirValueChanged(app, event)
            % Changed selection: enable apply/save buttons, del preset name
            selectionChangedState(app)
        end

        % Value changed function: AudiogramFileEditField
        function AudiogramFileEditFieldValueChanged(app, event)
            % Changed selection: enable apply/save buttons, del preset name
            selectionChangedState(app)
        end

        % Value changed function: PlayCalibrationFileButton
        function PlayCalibrationFileButtonValueChanged(app, event)

            % get settings from playback method and mode
            readSettingsFromInterface(app);
            initializePlaybackObjects(app);

            if app.PlayCalibrationFileButton.Value == 0

                % if already pressed, stop sound
                releaseAudioObjs(app);

            else % if pressed, play tone

                % Read calibration signal
                % TODO Change this to select speaker-specific SSN
                calibrationToneFullFile = fullfile(app.CurrentAudioDir.Value,'Noise','SSN_F1.wav');
                calibrationInfo = audioinfo(calibrationToneFullFile);

                % Read in random segment
                lengthCalSignal = 10*60*app.data.Settings.Fs; % hard-coded 10 min
                randSample = round((calibrationInfo.TotalSamples-lengthCalSignal)*rand);
                calibrationSignal = audioread(calibrationToneFullFile,...
                    [randSample, randSample+lengthCalSignal-1]);

                % Format, equalization, and scale
                calibrationSignal = replicateForStereoOrMulti(app,calibrationSignal);
                calibrationSignal = applyEqualizationFilter(app,calibrationSignal);

                % Play the calibration file
                playSignal(app,calibrationSignal);
            end
        end

        % Button pushed function: SelectAudioDirButton
        function SelectAudioDirButtonPushed(app, event)
            audiopath = uigetdir;

            if audiopath==0 % user pressed cancel
                drawnow;
                figure(app.UIFigure)
                return
            else
                app.CurrentAudioDir.Value = audiopath;
                drawnow;
                figure(app.UIFigure);
                % Changed selection: enable apply/save buttons, del preset name
                selectionChangedState(app)
            end
        end

        % Button pushed function: SelectVideoDirButton
        function SelectVideoDirButtonPushed(app, event)
            videopath = uigetdir;
            if videopath==0
                % user pressed cancel
                drawnow;
                figure(app.UIFigure)
                return
            else
                app.CurrentVideoDir.Value = videopath;
                drawnow;
                figure(app.UIFigure);
                % Changed selection: enable apply/save buttons, del preset name
                selectionChangedState(app)
            end
        end

        % Button pushed function: SelectEqualizationFilterButton
        function SelectEqualizationFilterButtonPushed(app, event)
            [filename,pathname] = uigetfile('*.mat');
            if filename==0 % user pressed cancel
                drawnow;
                figure(app.UIFigure);
                app.EqualizationFilterEditField.Value = '';
                return;
            else
                app.EqualizationFilterEditField.Value = fullfile(pathname,filename);
                drawnow;
                figure(app.UIFigure);
                % Changed selection: enable apply/save buttons, del preset name
                selectionChangedState(app)
            end
        end

        % Button pushed function: SavePresetButton
        function SavePresetButtonPushed(app, event)
            % Get user input for filename - workspace
            [file,path] = uiputfile(fullfile(app.pers.presetDir,'*.mat'),'Save settings as a preset');

            if file==0 % user pressed cancel
                drawnow;
                figure(app.UIFigure);
                return;
            else
                readSettingsFromInterface(app);
                setup = app.data.Settings;
                fullfilename = fullfile(path, file);
                save(fullfilename, 'setup', '-mat');
                [~,f,~]=fileparts(fullfilename);
                filename=fullfile(f);
                app.UploadedPresetEditField.Value = filename;
                app.pers.UploadedPreset = app.UploadedPresetEditField.Value;
                drawnow;
                figure(app.UIFigure);
                app.SavePresetButton.Enable = "off";
            end
            % Get focus back to the main figure
            focus(app.UIFigure);
        end

        % Button pushed function: ApplySettingsButton
        function ApplySettingsButtonPushed(app, event)

            readSettingsFromInterface(app);
            app.ApplySettingsButton.Enable = "off";

            % Export data to main function
            importSettings(app.CallingApp, app.data, app.pers)

            % Get focus back to the main figure
            focus(app.UIFigure);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.ApplySettingsButton.Enable == "on"
                selection = uiconfirm(app.UIFigure,'Do you want to apply or revert your changes?','Settings','Options',{'Apply','Revert'});
                if strcmpi(selection,'Apply')
                    ApplySettingsButtonPushed(app)
                    figure(app.CallingApp.UIFigure);
                    delete(app);
                elseif strcmpi(selection, 'Revert')
                    writeSettingsToInterface(app);
                    figure(app.CallingApp.UIFigure);
                    delete(app)
                else
                    % if user presses X to cancel
                    return
                end
            else
                % Apply settings and close app
                ApplySettingsButtonPushed(app)
                app.ApplySettingsButton.Enable = "off";
                figure(app.CallingApp.UIFigure);
                delete(app)
            end

        end

        % Button pushed function: LoadPresetButton
        function LoadPresetButtonPushed(app, event)

            [filename,pathname] = uigetfile(fullfile(app.pers.presetDir,'*.mat'));
            if filename==0
                % user pressed cancel
                drawnow;
                figure(app.UIFigure);
                return
            else
                % loading variable
                fullfilename = fullfile(pathname,filename);
                load(fullfilename);
                app.data.Settings = setup;

                [~,f,~]=fileparts(fullfilename);
                filename=fullfile(f);
                app.UploadedPresetEditField.Value = filename;
                app.pers.UploadedPreset = app.UploadedPresetEditField.Value;

                % Updating workspace settings with current variable
                writeSettingsToInterface(app)

                drawnow;
                figure(app.UIFigure);
            end

            % Get focus back to the main figure
            focus(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ResultTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.ResultTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ResultTypeButtonGroupSelectionChanged, true);
            app.ResultTypeButtonGroup.Title = 'Result type';
            app.PercentageCorrectButton = uiradiobutton(app.ResultTypeButtonGroup);
            app.PercentageCorrectButton.Text = 'Percentage correct';
            app.SpeechReceptionThresholdButton = uiradiobutton(app.ResultTypeButtonGroup);
            app.SpeechReceptionThresholdButton.Text = 'Speech reception threshold';
            app.SpeechReceptionThresholdButton.Value = true;
            app.AdaptiveMethodDropdown = uidropdown(app.ResultTypeButtonGroup);
            app.AdaptiveMethodDropdown.Items = {'Default'};
            app.AdaptiveMethodDropdown.Value = 'Default';
            app.AdaptiveMethodDropdownLabel = uilabel(app.ResultTypeButtonGroup);
            app.AdaptiveMethodDropdownLabel.Text = 'Method';
            app.StoppingCriteriaDecibelLabel = uilabel(app.ResultTypeButtonGroup);
            app.StoppingCriteriaDecibelLabel.Text = 'dB';
            app.StoppingCriteriaLabel = uilabel(app.ResultTypeButtonGroup);
            app.StoppingCriteriaLabel.Text = 'Stop if SE reaches';
            app.StoppingCriteriaEditField = uieditfield(app.ResultTypeButtonGroup, 'numeric');
            app.StoppingCriteriaEditField.Limits = [0 Inf];
            app.StoppingCriteriaEditField.ValueChangedFcn = createCallbackFcn(app, @StoppingCriteriaEditFieldValueChanged, true);
            app.ScoringTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.ScoringTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ScoringTypeButtonGroupSelectionChanged, true);
            app.ScoringTypeButtonGroup.Title = 'Scoring type';
            app.KeywordBasedButton = uiradiobutton(app.ScoringTypeButtonGroup);
            app.KeywordBasedButton.Text = 'Keyword-based';
            app.KeywordBasedButton.Value = true;
            app.WordByWordButton = uiradiobutton(app.ScoringTypeButtonGroup);
            app.WordByWordButton.Text = 'Word-by-word';
            app.PresentationModeButtonGroup = uibuttongroup(app.UIFigure);
            app.PresentationModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PresentationModeButtonGroupSelectionChanged, true);
            app.PresentationModeButtonGroup.Title = 'Presentation mode';
            app.AudioPresentationButton = uiradiobutton(app.PresentationModeButtonGroup);
            app.AudioPresentationButton.Text = 'Audio';
            app.AudioPresentationButton.Value = true;
            app.AudioVisualPresentationButton = uiradiobutton(app.PresentationModeButtonGroup);
            app.AudioVisualPresentationButton.Text = 'Audio-visual';
            app.SelectAudioDirButton = uibutton(app.PresentationModeButtonGroup, 'push');
            app.SelectAudioDirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAudioDirButtonPushed, true);
            app.SelectAudioDirButton.Text = 'Select';
            app.PathtofilesLabel = uilabel(app.PresentationModeButtonGroup);
            app.PathtofilesLabel.Enable = 'off';
            app.PathtofilesLabel.Text = 'Video path';
            app.CurrentVideoDir = uieditfield(app.PresentationModeButtonGroup, 'text');
            app.CurrentVideoDir.ValueChangedFcn = createCallbackFcn(app, @CurrentVideoDirValueChanged, true);
            app.CurrentVideoDir.Editable = 'off';
            app.CurrentVideoDir.Enable = 'off';
            app.SelectVideoDirButton = uibutton(app.PresentationModeButtonGroup, 'push');
            app.SelectVideoDirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVideoDirButtonPushed, true);
            app.SelectVideoDirButton.Enable = 'off';
            app.SelectVideoDirButton.Text = 'Select';
            app.PathtofilesLabel_2 = uilabel(app.PresentationModeButtonGroup);
            app.PathtofilesLabel_2.Text = 'Audio path';
            app.CurrentAudioDir = uieditfield(app.PresentationModeButtonGroup, 'text');
            app.CurrentAudioDir.ValueChangedFcn = createCallbackFcn(app, @CurrentAudioDirValueChanged, true);
            app.CurrentAudioDir.Editable = 'off';
            app.PlaybackMethodButtonGroup = uibuttongroup(app.UIFigure);
            app.PlaybackMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlaybackMethodButtonGroupSelectionChanged, true);
            app.PlaybackMethodButtonGroup.Title = 'Playback method';
            app.ChannelsOutEditFieldLabel = uilabel(app.PlaybackMethodButtonGroup);
            app.ChannelsOutEditFieldLabel.Text = 'Channel(s) out';
            app.ChannelsOutEditField = uieditfield(app.PlaybackMethodButtonGroup, 'text');
            app.ChannelsOutEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelsOutEditFieldValueChanged, true);
            app.ChannelsOutEditField.Tooltip = {'Specify the channel numbers for the output. Single and multiple values are accepted.'};
            app.ChannelsOutEditField.Value = '1 2';
            app.DriverDropDown = uidropdown(app.PlaybackMethodButtonGroup);
            app.DriverDropDown.Items = {'ASIO', 'DirectSound', 'WASAPI', 'ALSA', 'CoreAudio'};
            app.DriverDropDown.ValueChangedFcn = createCallbackFcn(app, @DriverDropDownValueChanged, true);
            app.DriverDropDown.Value = 'ASIO';
            app.DriverDropDownLabel = uilabel(app.PlaybackMethodButtonGroup);
            app.DriverDropDownLabel.Text = 'Driver';
            app.PlayrecButton = uiradiobutton(app.PlaybackMethodButtonGroup);
            app.PlayrecButton.Text = 'Playrec';
            app.AudioToolboxButton = uiradiobutton(app.PlaybackMethodButtonGroup);
            app.AudioToolboxButton.Text = 'Audio Toolbox';
            app.AudioToolboxButton.Value = true;
            app.BasicButton = uiradiobutton(app.PlaybackMethodButtonGroup);
            app.BasicButton.Text = 'Basic';
            app.ChannelsInEditFieldLabel = uilabel(app.PlaybackMethodButtonGroup);
            app.ChannelsInEditFieldLabel.Text = 'Channel(s) in';
            app.ChannelsInEditField = uieditfield(app.PlaybackMethodButtonGroup, 'text');
            app.ChannelsInEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelsInEditFieldValueChanged, true);
            app.ChannelsInEditField.Tooltip = {'Specify input devices, e.g. microphone channel.'};
            app.PlaybackmodeButtonGroup = uibuttongroup(app.UIFigure);
            app.PlaybackmodeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlaybackmodeButtonGroupSelectionChanged, true);
            app.PlaybackmodeButtonGroup.Title = 'Playback mode';
            app.StereoButton = uiradiobutton(app.PlaybackmodeButtonGroup);
            app.StereoButton.Text = 'Stereo';
            app.StereoButton.Value = true;
            app.MonoButton = uiradiobutton(app.PlaybackmodeButtonGroup);
            app.MonoButton.Enable = 'off';
            app.MonoButton.Text = 'Mono';
            app.MultiButton = uiradiobutton(app.PlaybackmodeButtonGroup);
            app.MultiButton.Enable = 'off';
            app.MultiButton.Text = 'Multi';
            app.CalibrationButtonGroup = uibuttongroup(app.UIFigure);
            app.CalibrationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CalibrationButtonGroupSelectionChanged, true);
            app.CalibrationButtonGroup.Title = 'Calibration';
            app.dBZLabel = uilabel(app.CalibrationButtonGroup);
            app.dBZLabel.Text = 'dB(Z)';
            app.MeasuredLabel = uilabel(app.CalibrationButtonGroup);
            app.MeasuredLabel.Text = 'Measured';
            app.PlayCalibrationFileButton = uibutton(app.CalibrationButtonGroup, 'state');
            app.PlayCalibrationFileButton.ValueChangedFcn = createCallbackFcn(app, @PlayCalibrationFileButtonValueChanged, true);
            app.PlayCalibrationFileButton.Text = 'Play';
            app.EqualizationfilterEditFieldLabel = uilabel(app.CalibrationButtonGroup);
            app.EqualizationfilterEditFieldLabel.Text = 'Equalization filter';
            app.EqualizationFilterEditField = uieditfield(app.CalibrationButtonGroup, 'text');
            app.EqualizationFilterEditField.ValueChangedFcn = createCallbackFcn(app, @EqualizationFilterEditFieldValueChanged, true);
            app.EqualizationFilterEditField.Editable = 'off';
            app.SelectEqualizationFilterButton = uibutton(app.CalibrationButtonGroup, 'push');
            app.SelectEqualizationFilterButton.ButtonPushedFcn = createCallbackFcn(app, @SelectEqualizationFilterButtonPushed, true);
            app.SelectEqualizationFilterButton.Text = 'Select';
            app.RightMeasuredEditFieldLabel = uilabel(app.CalibrationButtonGroup);
            app.RightMeasuredEditFieldLabel.Text = 'R';
            app.RightMeasuredEditField = uieditfield(app.CalibrationButtonGroup, 'numeric');
            app.RightMeasuredEditField.ValueChangedFcn = createCallbackFcn(app, @RightMeasuredEditFieldValueChanged, true);
            app.RightMeasuredEditField.Value = 95;
            app.LeftMeasuredEditField = uieditfield(app.CalibrationButtonGroup, 'numeric');
            app.LeftMeasuredEditField.ValueChangedFcn = createCallbackFcn(app, @LeftMeasuredEditFieldValueChanged, true);
            app.LeftMeasuredEditField.Value = 95;
            app.LeftMeasuredEditFieldLabel = uilabel(app.CalibrationButtonGroup);
            app.LeftMeasuredEditFieldLabel.Text = 'L';
            app.CalibrationEditFieldLabel = uilabel(app.CalibrationButtonGroup);
            app.CalibrationEditFieldLabel.Text = 'Calibration';
            app.CreateFilterEditFieldLabel = uilabel(app.CalibrationButtonGroup);
            app.CreateFilterEditFieldLabel.Text = 'Create filter';
            app.StartCreateFilterButton = uibutton(app.CalibrationButtonGroup, 'push');
            app.StartCreateFilterButton.ButtonPushedFcn = createCallbackFcn(app, @StartCreateFilterButtonPushed, true);
            app.StartCreateFilterButton.Text = 'Start';
            app.MiscellaneousButtonGroup = uibuttongroup(app.UIFigure);
            app.MiscellaneousButtonGroup.Title = 'Miscellaneous';
            app.ApplyHearingLossCompensationCheckbox = uicheckbox(app.MiscellaneousButtonGroup);
            app.ApplyHearingLossCompensationCheckbox.ValueChangedFcn = createCallbackFcn(app, @ApplyHearingLossCompensationCheckboxValueChanged, true);
            app.ApplyHearingLossCompensationCheckbox.Text = 'Apply hearing loss compensation';
            app.RandomizeOrderWithinListsCheckbox = uicheckbox(app.MiscellaneousButtonGroup);
            app.RandomizeOrderWithinListsCheckbox.ValueChangedFcn = createCallbackFcn(app, @RandomizeOrderWithinListsCheckboxValueChanged, true);
            app.RandomizeOrderWithinListsCheckbox.Text = 'Randomize order within lists';
            app.AdjustSentenceLevelRMSCheckbox = uicheckbox(app.MiscellaneousButtonGroup);
            app.AdjustSentenceLevelRMSCheckbox.ValueChangedFcn = createCallbackFcn(app, @AdjustSentenceLevelRMSCheckboxValueChanged, true);
            app.AdjustSentenceLevelRMSCheckbox.Text = 'Adjust sentence-level RMS';
            app.AdjustSentenceLevelRMSCheckbox.Value = true;
            app.AudiogramFileLabel = uilabel(app.MiscellaneousButtonGroup);
            app.AudiogramFileLabel.Enable = 'off';
            app.AudiogramFileLabel.Text = 'Audiograms';
            app.AudiogramFileSelectButton = uibutton(app.MiscellaneousButtonGroup, 'push');
            app.AudiogramFileSelectButton.ButtonPushedFcn = createCallbackFcn(app, @AudiogramFileSelectButtonPushed, true);
            app.AudiogramFileSelectButton.Enable = 'off';
            app.AudiogramFileSelectButton.Text = 'Select';
            app.AudiogramFileEditField = uieditfield(app.MiscellaneousButtonGroup, 'text');
            app.AudiogramFileEditField.ValueChangedFcn = createCallbackFcn(app, @AudiogramFileEditFieldValueChanged, true);
            app.AudiogramFileEditField.Editable = 'off';
            app.AudiogramFileEditField.Enable = 'off';
            app.MaskerButtonGroup = uibuttongroup(app.UIFigure);
            app.MaskerButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MaskerButtonGroupSelectionChanged, true);
            app.MaskerButtonGroup.Title = 'Masker';
            app.ContinuousButton = uiradiobutton(app.MaskerButtonGroup);
            app.ContinuousButton.Text = 'Continuous';
            app.IntermittentButton = uiradiobutton(app.MaskerButtonGroup);
            app.IntermittentButton.Text = 'Intermittent';
            app.IntermittentButton.Value = true;
            app.SecLabel = uilabel(app.MaskerButtonGroup);
            app.SecLabel.Text = 'sec';
            app.DurationbeforeEditFieldLabel = uilabel(app.MaskerButtonGroup);
            app.DurationbeforeEditFieldLabel.Text = 'Duration before';
            app.DurationbeforeEditField = uieditfield(app.MaskerButtonGroup, 'numeric');
            app.DurationbeforeEditField.Limits = [0 Inf];
            app.DurationbeforeEditField.ValueChangedFcn = createCallbackFcn(app, @DurationbeforeEditFieldValueChanged, true);
            app.DurationbeforeEditField.Value = 0.5;
            app.SavePresetButton = uibutton(app.UIFigure, 'push');
            app.SavePresetButton.ButtonPushedFcn = createCallbackFcn(app, @SavePresetButtonPushed, true);
            app.SavePresetButton.Text = 'Save preset';
            app.ApplySettingsButton = uibutton(app.UIFigure, 'push');
            app.ApplySettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplySettingsButtonPushed, true);
            app.ApplySettingsButton.Text = 'Apply settings';
            app.LoadPresetButton = uibutton(app.UIFigure, 'push');
            app.LoadPresetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPresetButtonPushed, true);
            app.LoadPresetButton.Text = 'Load preset';
            app.UploadedPresetEditField = uieditfield(app.UIFigure, 'text');
            app.UploadedPresetEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DASTsettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end