classdef DAST_V1_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        TestTab                       matlab.ui.container.Tab
        PausecontinueButton           matlab.ui.control.StateButton
        MarkallButton                 matlab.ui.control.StateButton
        Keyword3Button                matlab.ui.control.StateButton
        Keyword2Button                matlab.ui.control.StateButton
        Keyword1Button                matlab.ui.control.StateButton
        ExeptionsCheckBox             matlab.ui.control.CheckBox
        SentenceText                  matlab.ui.control.Label
        Instructions                  matlab.ui.control.Label
        Sentences                     matlab.ui.control.Label
        currentSent                   matlab.ui.control.Label
        currentList                   matlab.ui.control.Label
        SentenceNoLabel               matlab.ui.control.Label
        AcceptandcontinueButton       matlab.ui.control.Button
        DanskStningstestLabel         matlab.ui.control.Label
        StopButton                    matlab.ui.control.Button
        StarttestButton               matlab.ui.control.Button
        MoresettingsTab               matlab.ui.container.Tab
        MaskertimeEditField           matlab.ui.control.EditField
        MaskertimeEditFieldLabel      matlab.ui.control.Label
        secLabel                      matlab.ui.control.Label
        PresentationButtonGroup       matlab.ui.container.ButtonGroup
        VideosetupButton              matlab.ui.control.Button
        AudiovisualButton             matlab.ui.control.RadioButton
        AudioonlyButton               matlab.ui.control.RadioButton
        ScoringtypeButtonGroup        matlab.ui.container.ButtonGroup
        WordbywordButton              matlab.ui.control.RadioButton
        KeywordbasedButton            matlab.ui.control.RadioButton
        OutputsetupButtonGroup        matlab.ui.container.ButtonGroup
        CalibrationButton             matlab.ui.control.Button
        LoudspeakersButton            matlab.ui.control.RadioButton
        HeadphonesButton              matlab.ui.control.RadioButton
        TestmethodButtonGroup         matlab.ui.container.ButtonGroup
        AdaptiveButton                matlab.ui.control.RadioButton
        FixedSNRButton                matlab.ui.control.RadioButton
        SettingsPanel                 matlab.ui.container.Panel
        MaskerlevelEditField          matlab.ui.control.EditField
        MaskerlevelEditFieldLabel     matlab.ui.control.Label
        dBSPLLabel_2                  matlab.ui.control.Label
        dBSPLLabel                    matlab.ui.control.Label
        TargetlevelEditField          matlab.ui.control.EditField
        TargetlevelEditFieldLabel     matlab.ui.control.Label
        MaskertypeDropDown            matlab.ui.control.DropDown
        MaskertypeDropDownLabel       matlab.ui.control.Label
        ListnumberDropDown            matlab.ui.control.DropDown
        ListnumberDropDownLabel       matlab.ui.control.Label
        TalkerDropDown                matlab.ui.control.DropDown
        TalkerDropDownLabel           matlab.ui.control.Label
        BasictestinformationPanel     matlab.ui.container.Panel
        FilenameEditField             matlab.ui.control.EditField
        FilenameEditFieldLabel        matlab.ui.control.Label
        DateEditField                 matlab.ui.control.EditField
        DateEditFieldLabel            matlab.ui.control.Label
        SubjectNoEditField            matlab.ui.control.EditField
        SubjectNoEditFieldLabel       matlab.ui.control.Label
        TesterintiialsEditField       matlab.ui.control.EditField
        TesterintiialsEditFieldLabel  matlab.ui.control.Label
    end


    properties (Access = public)

        % % Definition of global variables 
        % % to use across different functions
        
        % File locations
        targetDir; maskerDir; targetFile; maskerFile;
        resultName; resultDir; resultFile; target; masker;

        % Settings
        maskerOnsetTime; maskerOffsetTime;
        testMethod;scoringType;
        listNum; talker; maskerType; targetLevel; maskerLevel;

        % current sentences
        sentences; sentencesDir; sent; sentTot; sentText; iteration;
        
        % results
        totalWordsCorrect;totalWordsTest; Percentage;
        soundObj 

    end

    methods (Access = public)

        function playSound(app)

            % Read the signals:
            [targetSignal,Fs] = audioread(app.target);
            [maskerSignal,Fs_masker] = audioread(app.masker);
            % (Combine target + masker)

            % Play sound object 
            % (for now, no calibration or mix with masker)
            app.soundObj = audioplayer(targetSignal, Fs);
            playblocking(app.soundObj);

        end
    end

    methods (Access = public)

        function updateInformation(app)

            app.iteration = app.iteration + 1;

            % ----------------------------------------------------

            % Get current sentence number and text
            app.sent = app.sentences.Number(app.iteration); % current sentence
            app.sentText = app.sentences.Sentence(app.sent); % current text sentence

            % Update fields
            app.currentSent.Text = sprintf('%i / %i', app.sent, app.sentTot);
            app.SentenceText.Text = app.sentText;
            app.currentList.Text = app.ListnumberDropDown.Value;

            Keywords = split(app.sentences.Keywords(app.sent), ',');
            app.Keyword1Button.Text = Keywords(1);
            app.Keyword2Button.Text = Keywords(2);
            app.Keyword3Button.Text = Keywords(3);

            % Taget and masker
            app.targetFile = sprintf('%04d.wav', app.sent); %targetInit = 'AG';
            app.maskerFile = strcat(app.maskerType, '.wav');
            app.target = fullfile(app.targetDir, app.targetFile);
            app.masker = fullfile(app.maskerDir, app.maskerFile);

        end
    end

    methods (Access = private)

        function disableButtons_initialState(app)

            % User input enabled
            app.StarttestButton.Enable = "on";
            app.BasictestinformationPanel.Enable = "on";
            app.SettingsPanel.Enable = "on";

            % Test tab disabled
            app.PausecontinueButton.Enable = "off";
            app.StopButton.Enable = "off";
            app.MarkallButton.Enable = "off";
            app.ExeptionsCheckBox.Enable = "off";

            app.Keyword1Button.Visible = "off";
            app.Keyword2Button.Visible = "off";
            app.Keyword3Button.Visible = "off";
            app.Sentences.Visible = "off";
            app.Instructions.Visible = "off";
            app.SentenceNoLabel.Visible = "off";
            app.currentList.Visible = "off";
            app.currentSent.Visible = "off";
            app.SentenceText.Visible = "off";

            app.AcceptandcontinueButton.Enable = "off";

        end

        function enableButtons_testState(app)

            % User input disabled
            app.StarttestButton.Enable = "off";
            app.BasictestinformationPanel.Enable = "off";
            app.SettingsPanel.Enable = "off";
            app.TabGroup.SelectedTab = app.TestTab;

            % Test tab enabled
            app.PausecontinueButton.Enable = "on";
            app.StopButton.Enable = "on";
            app.MarkallButton.Enable = "on";
            app.ExeptionsCheckBox.Enable = "on";

            app.Keyword1Button.Visible = "on";
            app.Keyword2Button.Visible = "on";
            app.Keyword3Button.Visible = "on";
            app.Sentences.Visible = "on";
            app.Instructions.Visible = "on";
            app.SentenceNoLabel.Visible = "on";
            app.currentList.Visible = "on";
            app.currentSent.Visible = "on";
            app.SentenceText.Visible = "on";
        end

        function getSettings(app)

            % ------------------------------------------------------------
            % Get config from 'Settings' and 'More settings' panel

            % Settings
            app.listNum = app.ListnumberDropDown.Value;
            app.talker = app.TalkerDropDown.Value;
            app.maskerType = app.MaskertypeDropDown.Value;
            app.targetLevel = double(string(app.TargetlevelEditField.Value));
            app.maskerLevel = double(string(app.MaskerlevelEditField.Value));

            % More settings
            app.maskerOnsetTime = double(string(app.MaskertimeEditField.Value)); % [sec.] time between noise onset and sentence start
            app.maskerOffsetTime = double(string(app.MaskertimeEditField.Value)); % [sec.] time between noise offset and end
            
            if app.FixedSNRButton.Value == 1; app.testMethod = 'Fixed SNR';
            else; app.testMethod = 'Adaptive SNR'; end
            if app.KeywordbasedButton.Value == 1; app.scoringType = 'Keyword-based';
            else; app.scoringType = 'Word-by-word'; end

            % Test method, rating, output setup, presentation...
            % ...
            % ...
            % ...

            % ------------------------------------------------------------

            % File locations:
            app.targetDir = fullfile(pwd, 'audio','Speech',...
                app.talker, app.listNum); % targets dir
            app.maskerDir = fullfile(pwd, 'audio','Noise'); % masker dir

            app.sentencesDir = fullfile(pwd, 'files', 'DAST-sentences.xlsx'); % file with DAST sentences
            app.resultDir = fullfile(pwd, 'results'); % results dir
            app.resultName = app.FilenameEditField.Value; % results file name
            app.resultFile = fullfile(app.resultDir, [app.resultName, '.csv']);
            % if csv file already exists (duplicating file name)
            newName = app.resultName;
            for i = 1:30
                if isfile(app.resultFile)
                    newName = strcat(app.resultName, '.', num2str(i));
                    app.resultFile = fullfile(app.resultDir, [newName, '.csv']);
                else
                    break
                end
            end
            if strcmp(app.resultName,newName) == 0
                app.resultName = newName;
            end

            audioFilesInFolder = dir(fullfile(app.targetDir,'*.wav'));
            app.sentTot = numel(audioFilesInFolder); % total num of sentences per list

            app.sentences = readtable(app.sentencesDir); % file with DAST sentences
            % ------------------------------------------------------------

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Intializing variables
            formatOut = 'yyyy_mm_dd';
            app.DateEditField.Value = datestr(now,formatOut);
            app.FilenameEditField.Value = sprintf(app.DateEditField.Value);

            % disable buttons
            disableButtons_initialState(app)


        end

        % Value changed function: DateEditField, FilenameEditField, 
        % ...and 2 other components
        function TestInfoChanged(app, event)
            if isempty(app.TesterintiialsEditField.Value); Leader = '';
            else; Leader = strcat('_', app.TesterintiialsEditField.Value); end
            if isempty(app.SubjectNoEditField.Value); Subject = '';
            else; Subject = strcat('TP', app.SubjectNoEditField.Value, '_'); end

            Date = app.DateEditField.Value;
            app.FilenameEditField.Value = sprintf(strcat(Subject, Date, Leader));
        end

        % Button pushed function: StarttestButton
        function StarttestButtonPushed(app, event)

            % Enable buttons
            enableButtons_testState(app)

            % Get information from user
            getSettings(app)

            %--------------------------------------------------------
            % write basic information in csv
            file = fopen(app.resultFile, 'w');
            fprintf(file, "Result file DAST test\n");
            fprintf(file, "Filename: %s\n", app.resultName);
            fprintf(file, "Date and time: %s\n", datestr(now,'dd-mmm-yyyy HH:MM:SS'));
            fprintf(file, "Procedure: %s\n", app.testMethod);
            fprintf(file, "Scoring: %s\n", app.scoringType);
            fprintf(file, "Start level of target: %i dB SPL\n", app.targetLevel);
            fprintf(file, "Start level of masker: %i dB SPL\n", app.maskerLevel);     
            fprintf(file, "Speaker: %s, Masker: %s\n", app.talker, app.maskerType);
            fprintf(file, "\n");
            fprintf(file, "List; Sentence; SNR; Correct; Total; Exceptions; Correct\n");
            fprintf(file, "number; index; [dB]; words; words; made; keywords\n");
            fclose(file);
            %--------------------------------------------------------
            
            % Update files names
            app.totalWordsCorrect = 0;
            app.totalWordsTest = 0;
            app.Percentage = 0;
            app.iteration = 0;
            updateInformation(app)

            % Play sound
            playSound(app)
            
            %--------------------------------------------------------

            % Accept and continue button: save results, play next sentence
            app.AcceptandcontinueButton.Enable = "on";


        end

        % Value changed function: MarkallButton
        function MarkallButtonValueChanged(app, event)
            value = app.MarkallButton.Value;
            if value == 1
                app.Keyword1Button.Value = 1;
                app.Keyword2Button.Value = 1;
                app.Keyword3Button.Value = 1;
            else
                app.Keyword1Button.Value = 0;
                app.Keyword2Button.Value = 0;
                app.Keyword3Button.Value = 0;
            end
        end

        % Value changed function: Keyword1Button, Keyword2Button, 
        % ...and 1 other component
        function KeywordSelected(app, event)
            K1 = app.Keyword1Button.Value;
            K2 = app.Keyword2Button.Value;
            K3 = app.Keyword3Button.Value;
            if K1 == 0 || K2 == 0 || K3 == 0
                app.MarkallButton.Value = 0;
            end
        end

        % Button pushed function: AcceptandcontinueButton
        function AcceptandcontinueButtonPushed(app, event)

            app.AcceptandcontinueButton.Enable = "off";
            %--------------------------------------------------------

            % calculate and save results in csv
            file = fopen(app.resultFile, 'a+');
            
            keywords = [];
            if app.Keyword1Button.Value == 1; keywords = [keywords, string(app.Keyword1Button.Text)];end
            if app.Keyword2Button.Value == 1; keywords = [keywords, string(app.Keyword2Button.Text)];end
            if app.Keyword3Button.Value == 1; keywords = [keywords, string(app.Keyword3Button.Text)];end

            correctKeywords = size(keywords,2);
            SNR = app.targetLevel - app.maskerLevel;
            totWordsSent = numel(strsplit(string(app.sentText)));
            exceptions = app.ExeptionsCheckBox.Value;

            app.totalWordsCorrect = app.totalWordsCorrect + correctKeywords;
%             app.totalWordsTest = app.totalWordsTest + totWordsSent;
            app.totalWordsTest = app.totalWordsTest + 3; % if total of keywords
            app.Percentage = (app.totalWordsCorrect/app.totalWordsTest)*100;

            if correctKeywords == 0
                fprintf(file, "%s; %i; %i; %i; %i; %i\n",...
                    erase(app.listNum, "List "), app.iteration, SNR, correctKeywords,...
                    totWordsSent, exceptions);
            elseif correctKeywords == 1
                fprintf(file, "%s; %i; %i; %i; %i; %i; %s\n",...
                    erase(app.listNum, "List "), app.iteration, SNR, correctKeywords,...
                    totWordsSent, exceptions, keywords);
            elseif correctKeywords == 2
                fprintf(file, "%s; %i; %i; %i; %i; %i; %s; %s\n",...
                    erase(app.listNum, "List "), app.iteration, SNR, correctKeywords,...
                    totWordsSent, exceptions, keywords);
            elseif correctKeywords == 3
                fprintf(file, "%s; %i; %i; %i; %i; %i; %s; %s; %s\n",...
                    erase(app.listNum, "List "), app.iteration, SNR, correctKeywords,...
                    totWordsSent, exceptions, keywords);
            end

            fclose(file);
            %--------------------------------------------------------

            app.Keyword1Button.Value = 0;
            app.Keyword2Button.Value = 0;
            app.Keyword3Button.Value = 0;
            app.MarkallButton.Value = 0;
            app.ExeptionsCheckBox.Value = 0;

            if app.iteration < app.sentTot

                %--------------------------------------------------------
                % Update file locations/names
                updateInformation(app)

                % Play sound
                playSound(app)
                %--------------------------------------------------------

                % Accept and continue button: save results, play next sentence
                app.AcceptandcontinueButton.Enable = "on";

            else
                disableButtons_initialState(app);
                app.SentenceText.Visible = "on";
                app.SentenceText.Text = strcat(app.listNum, ' scoring completed');

                % Write final results
                file = fopen(app.resultFile, 'a+');
                fprintf(file, "\n");
                fprintf(file, "Correct; Keywords; Percentage\n");
                fprintf(file, "%i; %i; %0.1f%%\n",...
                    app.totalWordsCorrect, app.totalWordsTest, app.Percentage);
                fclose(file);
            end


        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)

            % disable buttons
            disableButtons_initialState(app)
            app.SentenceText.Visible = "on";
            app.SentenceText.Text = 'Test terminated';

            % Write partial results
            file = fopen(app.resultFile, 'a+');
            fprintf(file, "Measurement cancelled\n");
            fprintf(file, "\n");
            fprintf(file, "Correct; Keywords; Percentage\n");
            fprintf(file, "%i; %i; %0.1f%%\n",...
                app.totalWordsCorrect, app.totalWordsTest, app.Percentage);
            fclose(file);

        end

        % Callback function
        function PausecontinueButtonPushed(app, event)


        end

        % Value changed function: PausecontinueButton
        function PausecontinueButtonValueChanged(app, event)

            % Pressing pause
            if app.PausecontinueButton.Value == 1

                % disable -some- buttons
                disableButtons_initialState(app)
                app.SentenceText.Visible = "on";
                app.SentenceText.Text = 'Test paused';
                app.SentenceNoLabel.Visible = "on";
                app.currentList.Visible = "on";
                app.currentSent.Visible = "on";
                app.PausecontinueButton.Enable = "on";

                % User input disabled
                app.StarttestButton.Enable = "off";
                app.BasictestinformationPanel.Enable = "off";
                app.SettingsPanel.Enable = "off";

            else
                % Pressing again for continuing test
                % enabling buttons
                enableButtons_testState(app)
                app.SentenceText.Text = app.sentText;

                % Playing sentence again
                playSound(app)

                app.AcceptandcontinueButton.Enable = "on";

            end

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)

            % While test is in progress, 'more settings' tab is disabled
            % (can't click on it)
            if app.StarttestButton.Enable == "off"
                app.TabGroup.SelectedTab = app.TestTab;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.BasictestinformationPanel = uipanel(app.GridLayout);
            app.BasictestinformationPanel.Title = 'Basic test information';
            app.TesterintiialsEditFieldLabel = uilabel(app.BasictestinformationPanel);
            app.TesterintiialsEditFieldLabel.Text = 'Tester intiials';
            app.TesterintiialsEditField = uieditfield(app.BasictestinformationPanel, 'text');
            app.TesterintiialsEditField.ValueChangedFcn = createCallbackFcn(app, @TestInfoChanged, true);
            app.SubjectNoEditFieldLabel = uilabel(app.BasictestinformationPanel);
            app.SubjectNoEditFieldLabel.Text = 'Subject No.';
            app.SubjectNoEditField = uieditfield(app.BasictestinformationPanel, 'text');
            app.SubjectNoEditField.ValueChangedFcn = createCallbackFcn(app, @TestInfoChanged, true);
            app.DateEditFieldLabel = uilabel(app.BasictestinformationPanel);
            app.DateEditFieldLabel.Text = 'Date';
            app.DateEditField = uieditfield(app.BasictestinformationPanel, 'text');
            app.DateEditField.ValueChangedFcn = createCallbackFcn(app, @TestInfoChanged, true);
            app.DateEditField.Editable = 'off';
            app.FilenameEditFieldLabel = uilabel(app.BasictestinformationPanel);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.BasictestinformationPanel, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @TestInfoChanged, true);
            app.FilenameEditField.Editable = 'off';
            app.SettingsPanel = uipanel(app.GridLayout);
            app.SettingsPanel.Title = 'Settings';
            app.TalkerDropDownLabel = uilabel(app.SettingsPanel);
            app.TalkerDropDownLabel.Text = 'Talker';
            app.TalkerDropDown = uidropdown(app.SettingsPanel);
            app.TalkerDropDown.Items = {'Female 1', 'Female 2', 'Male 1', 'Male 2', 'Mixed/random'};
            app.TalkerDropDown.Value = 'Female 1';
            app.ListnumberDropDownLabel = uilabel(app.SettingsPanel);
            app.ListnumberDropDownLabel.Text = 'List number';
            app.ListnumberDropDown = uidropdown(app.SettingsPanel);
            app.ListnumberDropDown.Items = {'List 1', 'List 2', 'List 3', 'List 4', 'List 5', 'List 6', 'List 7', 'List 8', 'List 9', 'List 10', 'List 11', 'List 12', 'List 13', 'List 14', 'List 15', 'List 16', 'List 17', 'List 18', 'List 19', 'List 20', 'List 21', 'List 22', 'List 23', 'List 24', 'List 25', 'List 26'};
            app.ListnumberDropDown.Value = 'List 1';
            app.MaskertypeDropDownLabel = uilabel(app.SettingsPanel);
            app.MaskertypeDropDownLabel.Text = 'Masker type';
            app.MaskertypeDropDown = uidropdown(app.SettingsPanel);
            app.MaskertypeDropDown.Items = {'Unmodulated', 'Modulated', 'Sinusoidal', '4T-Babble'};
            app.MaskertypeDropDown.Value = 'Unmodulated';
            app.TargetlevelEditFieldLabel = uilabel(app.SettingsPanel);
            app.TargetlevelEditFieldLabel.Text = 'Target level';
            app.TargetlevelEditField = uieditfield(app.SettingsPanel, 'text');
            app.TargetlevelEditField.Value = '70';
            app.dBSPLLabel = uilabel(app.SettingsPanel);
            app.dBSPLLabel.Text = 'dB SPL';
            app.dBSPLLabel_2 = uilabel(app.SettingsPanel);
            app.dBSPLLabel_2.Text = 'dB SPL';
            app.MaskerlevelEditFieldLabel = uilabel(app.SettingsPanel);
            app.MaskerlevelEditFieldLabel.Text = 'Masker level';
            app.MaskerlevelEditField = uieditfield(app.SettingsPanel, 'text');
            app.MaskerlevelEditField.Value = '70';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.TestTab = uitab(app.TabGroup);
            app.TestTab.Title = 'Test';
            app.StarttestButton = uibutton(app.TestTab, 'push');
            app.StarttestButton.ButtonPushedFcn = createCallbackFcn(app, @StarttestButtonPushed, true);
            app.StarttestButton.Text = 'Start test';
            app.StopButton = uibutton(app.TestTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.DanskStningstestLabel = uilabel(app.TestTab);
            app.DanskStningstestLabel.Text = 'Dansk Sætningstest';
            app.AcceptandcontinueButton = uibutton(app.TestTab, 'push');
            app.AcceptandcontinueButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptandcontinueButtonPushed, true);
            app.AcceptandcontinueButton.Text = 'Accept and continue';
            app.SentenceNoLabel = uilabel(app.TestTab);
            app.SentenceNoLabel.Text = 'Sentence No.';
            app.currentList = uilabel(app.TestTab);
            app.currentList.Tag = 'currentList';
            app.currentList.Text = 'currentList';
            app.currentSent = uilabel(app.TestTab);
            app.currentSent.Tag = 'currentSent';
            app.currentSent.Text = 'currentSent';
            app.Sentences = uilabel(app.TestTab);
            app.Sentences.Text = 'Sentence';
            app.Instructions = uilabel(app.TestTab);
            app.Instructions.Text = 'Mark correctly repeated keywords';
            app.SentenceText = uilabel(app.TestTab);
            app.SentenceText.Tag = 'SentenceText';
            app.SentenceText.Text = 'SentenceText';
            app.ExeptionsCheckBox = uicheckbox(app.TestTab);
            app.ExeptionsCheckBox.Text = 'Exeptions';
            app.Keyword1Button = uibutton(app.TestTab, 'state');
            app.Keyword1Button.ValueChangedFcn = createCallbackFcn(app, @KeywordSelected, true);
            app.Keyword1Button.Text = 'Keyword 1';
            app.Keyword2Button = uibutton(app.TestTab, 'state');
            app.Keyword2Button.ValueChangedFcn = createCallbackFcn(app, @KeywordSelected, true);
            app.Keyword2Button.Text = 'Keyword 2';
            app.Keyword3Button = uibutton(app.TestTab, 'state');
            app.Keyword3Button.ValueChangedFcn = createCallbackFcn(app, @KeywordSelected, true);
            app.Keyword3Button.Text = 'Keyword 3';
            app.MarkallButton = uibutton(app.TestTab, 'state');
            app.MarkallButton.ValueChangedFcn = createCallbackFcn(app, @MarkallButtonValueChanged, true);
            app.MarkallButton.Text = 'Mark all';
            app.PausecontinueButton = uibutton(app.TestTab, 'state');
            app.PausecontinueButton.ValueChangedFcn = createCallbackFcn(app, @PausecontinueButtonValueChanged, true);
            app.PausecontinueButton.Text = 'Pause/continue';
            app.MoresettingsTab = uitab(app.TabGroup);
            app.MoresettingsTab.Title = 'More settings';
            app.TestmethodButtonGroup = uibuttongroup(app.MoresettingsTab);
            app.TestmethodButtonGroup.Title = 'Test method';
            app.FixedSNRButton = uiradiobutton(app.TestmethodButtonGroup);
            app.FixedSNRButton.Text = 'Fixed SNR';
            app.FixedSNRButton.Value = true;
            app.AdaptiveButton = uiradiobutton(app.TestmethodButtonGroup);
            app.AdaptiveButton.Text = 'Adaptive';
            app.OutputsetupButtonGroup = uibuttongroup(app.MoresettingsTab);
            app.OutputsetupButtonGroup.Title = 'Output setup';
            app.HeadphonesButton = uiradiobutton(app.OutputsetupButtonGroup);
            app.HeadphonesButton.Text = 'Headphones';
            app.HeadphonesButton.Value = true;
            app.LoudspeakersButton = uiradiobutton(app.OutputsetupButtonGroup);
            app.LoudspeakersButton.Text = 'Loudspeakers';
            app.CalibrationButton = uibutton(app.OutputsetupButtonGroup, 'push');
            app.CalibrationButton.Text = 'Calibration';
            app.ScoringtypeButtonGroup = uibuttongroup(app.MoresettingsTab);
            app.ScoringtypeButtonGroup.Title = 'Scoring type';
            app.KeywordbasedButton = uiradiobutton(app.ScoringtypeButtonGroup);
            app.KeywordbasedButton.Text = 'Keyword-based';
            app.KeywordbasedButton.Value = true;
            app.WordbywordButton = uiradiobutton(app.ScoringtypeButtonGroup);
            app.WordbywordButton.Text = 'Word-by-word';
            app.PresentationButtonGroup = uibuttongroup(app.MoresettingsTab);
            app.PresentationButtonGroup.Title = 'Presentation';
            app.AudioonlyButton = uiradiobutton(app.PresentationButtonGroup);
            app.AudioonlyButton.Text = 'Audio only';
            app.AudioonlyButton.Value = true;
            app.AudiovisualButton = uiradiobutton(app.PresentationButtonGroup);
            app.AudiovisualButton.Text = 'Audiovisual';
            app.VideosetupButton = uibutton(app.PresentationButtonGroup, 'push');
            app.VideosetupButton.Text = 'Video setup';
            app.secLabel = uilabel(app.MoresettingsTab);
            app.secLabel.Text = 'sec';
            app.MaskertimeEditFieldLabel = uilabel(app.MoresettingsTab);
            app.MaskertimeEditFieldLabel.Text = 'Masker time';
            app.MaskertimeEditField = uieditfield(app.MoresettingsTab, 'text');
            app.MaskertimeEditField.Value = '3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DAST_V1_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end